classdef app_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SaveTreeButton                  matlab.ui.control.Button
        LoadTreeButton                  matlab.ui.control.Button
        Convert_LOG_Button              matlab.ui.control.Button
        InfoButton                      matlab.ui.control.Button
        Clear_All_Button                matlab.ui.control.Button
        Clear_All_SignalsButton         matlab.ui.control.Button
        Tree_Messages_Signals           matlab.ui.container.Tree
        SignalListBox                   matlab.ui.control.ListBox
        SignallistListBoxLabel          matlab.ui.control.Label
        MessageListBox                  matlab.ui.control.ListBox
        MessagelistLabel                matlab.ui.control.Label
        Search_Message_Edit             matlab.ui.control.EditField
        SearchEditFieldLabel            matlab.ui.control.Label
        Time_step_Value_EditField       matlab.ui.control.NumericEditField
        TimestepEditFieldLabel          matlab.ui.control.Label
        ParallelmodeSwitchLabel         matlab.ui.control.Label
        FoldermodeSwitchLabel           matlab.ui.control.Label
        Confirm_DBC_path_Button         matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        Save_name_EditField             matlab.ui.control.EditField
        SavenameEditFieldLabel          matlab.ui.control.Label
        BrowseSavefolderButton          matlab.ui.control.Button
        SavepathEditField               matlab.ui.control.EditField
        SavepathEditFieldLabel          matlab.ui.control.Label
        Parallel_mode_Switch            matlab.ui.control.ToggleSwitch
        Folder_mode_Switch              matlab.ui.control.ToggleSwitch
        Parallel_status_lamp            matlab.ui.control.Lamp
        ParaccessstatusLampLabel        matlab.ui.control.Label
        Browse_LOG_filesButton          matlab.ui.control.Button
        LOGpathEditField                matlab.ui.control.EditField
        LOGpathEditFieldLabel           matlab.ui.control.Label
        EmptyfilestatusLamp             matlab.ui.control.Lamp
        EmptyfilestatusLampLabel        matlab.ui.control.Label
        DBCsetsButtonGroup              matlab.ui.container.ButtonGroup
        CustomButton                    matlab.ui.control.RadioButton
        Bus133Button                    matlab.ui.control.RadioButton
        Bus129Button                    matlab.ui.control.RadioButton
        Bus127Button                    matlab.ui.control.RadioButton
        Bus123Button                    matlab.ui.control.RadioButton
        Bus121Button                    matlab.ui.control.RadioButton
        Memorry_Controller_ButtonGroup  matlab.ui.container.ButtonGroup
        Gb_64_button                    matlab.ui.control.RadioButton
        Gb_32_button                    matlab.ui.control.RadioButton
        Gb_16_button                    matlab.ui.control.RadioButton
        Browse_DBC_pathButton           matlab.ui.control.Button
        DBCpathEditField                matlab.ui.control.EditField
        DBCpathEditFieldLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        LOGs_files % Список лог файлов, которые нам нужны
        list_box_all_messages % Полный список сообщений
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Confirm_DBC_path_Button
        function Confirm_DBC_path_ButtonPushed(app, event)
            app.Tree_Messages_Signals.Children.delete;
            app.SignalListBox.Enable = false;
            app.SignalListBox.Value = {};
            app.SignalListBox.Items = {};
            app.Clear_All_SignalsButton.Enable = false;
            app.Search_Message_Edit.Enable = false;
            app.LoadTreeButton.Enable = false;
            app.SaveTreeButton.Enable = false;

            % Полный путь до директории приложения
            [filepath,~,~] = fileparts(mfilename('fullpath'));

            % В зависимости от выбранного режима выбираем либо пресеты, либо прописанный путь
            try
                selectedButton = app.DBCsetsButtonGroup.SelectedObject;
                switch selectedButton.Text
                    case 'Bus 121'
                        DBC_path = horzcat(filepath,'\Bus_6282_0000121');
                    case 'Bus 123'
                        DBC_path = horzcat(filepath,'\Bus_6282_0000123');
                    case 'Bus 127'
                        DBC_path = horzcat(filepath,'\Bus_6282_0000127');
                    case 'Bus 129'
                        DBC_path = horzcat(filepath,'\Bus_6282_0000129');
                    case 'Bus 133'
                        DBC_path = horzcat(filepath,'\Bus_6282_0000133');
                    case 'Custom'
                        DBC_path = app.DBCpathEditField.Value;
                end
            catch
                errordlg('Incorrect DBC file','DBC files error');
                return;
            end

            % Читаем информацию, о всех DBC файлах, которые лежат в DBC_path папке
            DBC_info = dir(append(DBC_path,'\*.dbc'));
            % Проверяем подгрузились ли DBC файлы
            if ~isempty(DBC_info)
                % Загружаем DBC файлы в один массив
                for i=1:numel(DBC_info)
                    canDB(i) = canDatabase(append(DBC_path,'\',DBC_info(i).name));
                end
                % Разблокируем выбор сообщений и сигналов
                app.MessageListBox.Enable = true;
                app.SignalListBox.Enable = true;
                app.Tree_Messages_Signals.Enable = true;
                app.Search_Message_Edit.Enable = true;
                if ~strcmp(selectedButton.Text,'Custom')
                    app.LoadTreeButton.Enable = true;
                    app.SaveTreeButton.Enable = true;
                end
                app.Search_Message_Edit.Value = '';

                % Формируем список всех сообщений
                DBC_message_list(1) = {0};
                DBC_id_list(1) = {0};
                DBC_DBC_name_list(1) = {0};
                for i = 1:numel(DBC_info)
                    DBC_message_list = vertcat(DBC_message_list, canDB(1, i).Messages);
                    DBC_id_list = vertcat(DBC_id_list, canDB(1, i).MessageInfo.ID);
                    DBC_DBC_name_list = vertcat(DBC_DBC_name_list, repmat({canDB(1, i).Name}, length(canDB(1, i).Messages), 1));
                    number_of_message_in_dbc(i) = length(canDB(1, i).Messages);
                end
                DBC_message_list(1) = [];
                DBC_id_list(1) = [];
                DBC_DBC_name_list(1) = [];
                % Ищем дублированные сообщения
                [~, unique_indeces] = unique(DBC_message_list,'stable');
                % Ищем индексы дубликатов
                duplicate_indices = setdiff( 1:numel(DBC_message_list), unique_indeces );
                % Записываем все сообщения, которые дублируются
                dublicate_message_list = unique(DBC_message_list(duplicate_indices));
                
                % Ищем индексы дублирующихся сообщений
                logical_count = ismember(DBC_message_list,dublicate_message_list);
                % Берем индексы этих элементов
                indexes = find(logical_count);

                % Ко всме сообщением, которые дублируются добавляем их id и их dbc
                DBC_message_list(indexes) = strcat(DBC_message_list(indexes),' \ id:',cellfun(@num2str,DBC_id_list(indexes),'UniformOutput',false),' \ dbc:',DBC_DBC_name_list(indexes));
                
                % Убираем дублированные сообщения
                app.MessageListBox.Items = sort(DBC_message_list);

                % Запоминаем общий список сообщений
                app.list_box_all_messages = sort(DBC_message_list); 
            end    
        end

        % Button pushed function: Convert_LOG_Button
        function Convert_LOG_ButtonPushed(app, event)
            % Засекаем время для тестирования
            time_test_start = tic;
            
            if isempty(app.LOGpathEditField.Value)
                errordlg('Files not found','File Error');
                return;
            end
            selectedButton = app.Memorry_Controller_ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case '64 Gb'
                    File_size = 500000;
                case '32 Gb'
                    File_size = 250000;
                case '16 Gb'
                    File_size = 125000;
            end
            if isempty(app.Tree_Messages_Signals.Children)
                errordlg('Messages or signals not found','File Error');
                return;
            end
            % Проверяем правильность написания имя файла
            if isempty(app.Save_name_EditField.Value)
                File_save_name = 'LOG.mat';
            else
                % Проверяем есть ли недопустимые символы в имени:
                if ~isempty(regexp(app.Save_name_EditField.Value,'[/\\*:?"<>|]', 'once')) % match
                    errordlg('Save name is invalid','File Error');
                    return
                else
                    File_save_name = strcat(app.Save_name_EditField.Value,'.mat');
                end
            end

            % Ставим лампу на зеленый = пустых файлов нет
            app.EmptyfilestatusLamp.Color = 'g';
            pause(.1);

            % Создаем стиль для отметки отсутствующих во всех файлах сигналов
            Absent_style = uistyle('BackgroundColor','#e06c6c');

            % Запускаем прогресс бар
            bar = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Processing the data');            

            % Инициализируем список сообщений, как дочерние узлы главного дререва
            message_list = {app.Tree_Messages_Signals.Children.Text};
            % Инициализируем список сигналов, как дочерние узлы дочерних узлов главного дререва
            signal_list = {};
            for i=1:length(message_list)
                node = app.Tree_Messages_Signals.Children(i);
                signal_list = horzcat(signal_list,{node.Children.Text});
            end

            % Красим все узлы дерева в исходный цвет
            Common_style = uistyle('FontColor','black','BackgroundColor','white');
            addStyle(app.Tree_Messages_Signals,Common_style)            
            
            % Полный путь до директории приложения
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            % В зависимости от выбранного режима выбираем либо пресеты, либо прописанный путь
            selectedButton = app.DBCsetsButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Bus 121'
                    DBC_path = horzcat(filepath,'\Bus_6282_0000121');
                case 'Bus 123'
                    DBC_path = horzcat(filepath,'\Bus_6282_0000123');
                case 'Bus 127'
                    DBC_path = horzcat(filepath,'\Bus_6282_0000127');
                case 'Bus 129'
                    DBC_path = horzcat(filepath,'\Bus_6282_0000129');
                case 'Bus 133'
                    DBC_path = horzcat(filepath,'\Bus_6282_0000133');
                case 'Custom'
                    DBC_path = app.DBCpathEditField.Value;
            end
            % Читаем информацию, о всех DBC файлах, которые лежат в DBC_path папке
            
            DBC_info = dir(append(DBC_path,'\*.dbc'));
            % Загружаем DBC файлы в один массив
            for i=1:numel(DBC_info)
                canDB(i) = canDatabase(append(DBC_path,'\',DBC_info(i).name));
            end

            % Создаем таблицу сигналов, сообщений и dbc
            [signal_and_multiplex] = signal_and_multiplex_table_func(message_list, signal_list, canDB, app);

            % Считываем информацию о временном шаге
            dt = app.Time_step_Value_EditField.Value;

            % Смотрим, какие режимы выбраны:
            % Параллельный/последовательный (Off = Последовательный)
            parallel_mode = app.Parallel_mode_Switch.Value;
            % Фолдер/файлы (Off = файлы)
            folder_mode = app.Folder_mode_Switch.Value;

            % Если работаем с файлами, то считываем записанный фолдер
            if strcmp(folder_mode,'Off')            
                % Считываем информации о фолдере нужных логов
                LOG_path = app.LOGpathEditField.Value;
                % Записываем путь сохранения
                if isempty(app.SavepathEditField.Value)
                    Extract_signals_save_path = LOG_path([1:end-1]);
                else
                    Extract_signals_save_path = app.SavepathEditField.Value;
                end
    
                % Если указывается один файл, то он не записывается как ячека
                % Записываем как ячейка
                if ~iscell(app.LOGs_files)
                    % Создаем временную переменную для имени
                    temp_name = app.LOGs_files;
                    clear app.LOGs_files
                    % Присваеваем в имена файлов запомненное имя, но уже как ячейк
                    app.LOGs_files = {temp_name};
                    clear temp_name
                end
                % Если работаем в последовательном режиме
                if strcmp(parallel_mode,'Off')
                    % Читаем информацию, о всех LOG файлах, которые лежат в выбранной папке
                    % и перобразуем их слогласно выбранному типу
                    % Создаем обычный массив имен файлов
                    Log_files_info = convertCharsToStrings(app.LOGs_files);
                    [~,~,indx_type_str] = fileparts(app.LOGs_files{1});
                    switch indx_type_str
        
                        case ".MF4"
                            % Проходим по всем MDF файлам и смотрим законченный ли он. Если нет, то заканчиваем.
                            for i=1:numel(Log_files_info)
                                % Заканчиваем незаконченный лог и создаем его копию с припиской "_fin" на конце
                                % Если у файла нет приписки "fin_" в начале, то значит мы его не заканчивали, тогда закончим его
                                if isempty(strfind(Log_files_info(i),'fin_'))
                                    try
                                        finalizedPath = mdfFinalize(strcat(LOG_path, '\', Log_files_info(i)), strcat(LOG_path, '\', "fin_", Log_files_info(i)));
                                    catch ME
                                        disp(ME.message)
                                    end
                                end
                            end
                            % Читаем информацию, о всех LOG файлах, которые лежат в Log_files_info папке, 
                            % теперь к ним могли добавиться законченные или обработанные, значит их общее количество увеличилось
                            % Теперь нужные нам логи финализированы, смотрим финализированные логи в рабочей директории
                            Log_files_info = dir(append(LOG_path,'\fin_*.MF4'));
                            number_of_logs_files = numel(app.LOGs_files);
        
                        case ".asc"
                            % Проходим по всем ASC файлам 
                            for i=1:numel(Log_files_info)
                                % Проходя по каждому ASC файлу убираем его шапку, чтобы его можно было прочитать
                                fid = fopen(strcat(LOG_path, '\', Log_files_info(i)), 'r') ;        % Open source file.
                                buffer = fread(fid, Inf) ;                                          % Read rest of the file.
                                fclose(fid);
                                % Теперь создадим копию без шапки, с которой будем работать
                                fid = fopen(strcat(LOG_path, '\', "fin_", Log_files_info(i)), 'w')  ;  % Open destination file.
                                fwrite(fid, buffer) ;                                                  % Save to file.
                                fclose(fid) ;
                            end
                            % Очищаем буфер считанного файла
                            clear buffer
                            % Читаем информацию, о всех LOG файлах, которые лежат в Log_files_info папке, 
                            % теперь к ним могли добавиться законченные или обработанные, значит их общее количество увеличилось
                            Log_files_info = dir(append(LOG_path,'\fin_*.asc'));
                            number_of_logs_files = numel(app.LOGs_files);
        
                        case ".log"
                            % Проходим по всем LOG файлам 
                            for i=1:numel(Log_files_info)
                                % Проходя по каждому LOG файлу убираем его шапку, чтобы его можно было прочитать
                                fid = fopen(strcat(LOG_path, '\', Log_files_info(i)), 'r') ;        % Open source file.
                                buffer = fread(fid, Inf) ;                                          % Read rest of the file.
                                fclose(fid);
                                
                                fid = fopen(strcat(LOG_path, '\', "fin_", Log_files_info(i)), 'w')  ;  % Open destination file.
                                fwrite(fid, buffer) ;                                                  % Save to file.
                                fclose(fid) ;
                            end
                            % Очищаем буфер считанного файла
                            clear buffer
                            % Читаем информацию, о всех LOG файлах, которые лежат в Log_files_info папке, 
                            % теперь к ним могли добавиться законченные или обработанные, значит их общее количество увеличилось
                            Log_files_info = dir(append(LOG_path,'\fin_*.log'));
                            number_of_logs_files = numel(app.LOGs_files);
        
                        case ".blf"
                            % Проходим по все blf файлам и копируем их в папку 'C:\Temp', чтобы избежать проблем с кириллицей
                            for i=1:numel(Log_files_info)
                                copyfile( strcat(LOG_path, '\', Log_files_info(i)), strcat(LOG_path, '\', "fin_", Log_files_info(i)) );
                                copyfile( strcat(LOG_path, '\', "fin_", Log_files_info(i)), 'C:\Temp' );
                            end
                            % Создаем массив данных о blf-ках во временной директории
                            Log_files_copy_folder = dir(append('C:\Temp','\*.blf'));
                            % Разбиваем blf-ки во временной директории на ASC-шки
                            % Сообщаем о том, что началось разбиение
                            bar.Message = 'Splitting .blf files';
                            % В качестве вывода получаем количество таких файлов
                            count_of_asc = blfSplitToAsc(unique([(signal_and_multiplex{6,:})]), canDB, 'C:\Temp', app.LOGs_files, File_size);
                            % Удаляем финализированные blf во временной директории
                            recycle("off")
                            % Ищем blf во временной директории
                            fin_Log_info_temp = dir(append('C:\Temp','\fin_*.blf'));
                            % Удаляем их из временного фолдера
                            for i=1:numel(fin_Log_info_temp)
                                delete(append('C:\Temp','\',fin_Log_info_temp(i).name));
                            end
                            recycle("on")
                            % Подготовим значения Log_files_info и app.LOGs_files для обработки через asc
                            Log_files_info = dir(append('C:\Temp','\fin_*.txt'));
                            number_of_logs_files = count_of_asc;
                    end
        
                    % Инициализируем переменную, которая будет считать в скольки файлах отстутвуют ВСЕ нужные нам сигналы
                    fail_file_count = 0;
        
                    % Показываем на прогресс баре, что мы начали
                    bar.Message = 'Start';
                    % Инициализируем переменные для подсчета времени
                    iteration_time = 0;
                    count_time = 0;
                    % Проходим по каждому финализированному логу из Log_files_info
                    for i = 1:numel(Log_files_info)
                        % В зависимости от выбраннго типа применяем функции чтения и прикладываем DBC
                        switch indx_type_str
                            case ".MF4" % Значит MF4
                                % Создаем массив имен файлов, которые нам нужны
                                    Log_files_name = strcat(LOG_path, '\', {Log_files_info.name});
                                    % Считываем все финализированные MF4 в один датасет
                                    dataAll_datastore = mdfDatastore( Log_files_name );
                                    % Находим индексы непустых Channel Group
                                    number_of_nonempty = find(dataAll_datastore.ChannelGroups.DataSize>0);
                                    % Создаем шаг чтения
                                    dataAll_datastore.ReadSize = 'file';
                                    % Если в списке присутствуют файлы больших размеров, то читаем не пофайлово а по количественно
                                    for i_size = 1:numel(Log_files_info)
                                        if Log_files_info(i_size).bytes > 100000*1024
                                            dataAll_datastore.ReadSize = '50000';
                                            break
                                        end
                                    end
                                    % Инициализируем счетчика итераций
                                    iteration_mdf = 0;
                                    % Инициализируем начальное время
                                    time_all = 0;
                                    % Инициализируем общую таблицу
                                    dataAll = timetable.empty;
                                    for i_channel=1:length(number_of_nonempty)
                                        % Выбираем канал для чтения
                                        dataAll_datastore.SelectedChannelGroupNumber = number_of_nonempty(i_channel);
                                        % Ресетаем датастор
                                        reset(dataAll_datastore);
                                        % Считываем дату пошагово
                                        while hasdata(dataAll_datastore)
                                            % Фиксируем начала счета
                                            t_iter_start = tic;   
                                            % Увеличиваем счтетчик итераций
                                            iteration_mdf = iteration_mdf + 1;
                                            % timetable_data = readall(dataAll,"UseParallel",true);
                                            timetable_data = read(dataAll_datastore);
                                            % Если таблица непустая, то обрабатываем ее, если она пустая, значит на самом деле среди number_of_nonempty имеются лишние каналы, 
                                            % которых нет в некоторых файлах, значит мы не пытаемся обработать пустую таблицу, а переходим к следующему каналу
                                            if ~isempty(timetable_data)
                                                % Удаляем из записи все те сообщения, ID которых нет в списке нужных нам
                                                logical_count = ismember( uint32(timetable_data.CAN_DataFrame_ID), unique([(signal_and_multiplex{6,:})]) );
                                                % Берем индексы этих элементов
                                                indexes = find(logical_count);
                                                % Удаляем из таблицы все остальные индексы, т.е. удаляем из таблицы те сообщения, которые нам не нужны
                                                timetable_data = timetable_data(indexes,:);
                                                % if iteration_mdf == 1                            
                                                %     dataAll = timetable_data;
                                                % else
                                                %     dataAll = [dataAll;timetable_data];
                                                % end
                                                dataAll = [dataAll;timetable_data];

                                                % Фиксируем конец счета
                                                t_iter_end = toc(t_iter_start);
                                                % Считаем среднее арифметическое времени, потраченого на счет:
                                                % Суммарное время:
                                                time_all = time_all + t_iter_end;
                                                % Среднее арифметическое времени
                                                t_medium = time_all/iteration_mdf;
                                                % Считаем количество оставшихся файлов
                                                number_of_remain_files = number_of_logs_files * length(number_of_nonempty) - iteration_mdf;
                                                % Считаем оставшееся время:
                                                remaining_time_sec = round(t_medium*number_of_remain_files,2);
                                                % Создаем строку времени
                                                time_row = horzcat(num2str(remaining_time_sec),' sec remains.');
                                                % Переводим секунды в минуты
                                                if remaining_time_sec > 60
                                                    remaining_time_minute = fix(remaining_time_sec/60);
                                                    remaining_time_sec = mod(remaining_time_sec,60);
                                                    time_row = horzcat(num2str(remaining_time_minute), ' min ',num2str(remaining_time_sec),' sec remains.');
                                                    % Переводим минуты в часы
                                                    if remaining_time_minute > 60
                                                        remaining_time_hours = fix(remaining_time_minute/60);
                                                        remaining_time_minute = mod(remaining_time_minute,60);
                                                        time_row = horzcat(num2str(remaining_time_hours), 'h',num2str(remaining_time_minute), ' min ',num2str(remaining_time_sec),' sec remains.');
                                                    end
                                                end
            
                                                % Продвигаем прогресс бар
                                                bar.Value = iteration_mdf/(number_of_logs_files*length(number_of_nonempty));
                                                bar.Message = sprintf('%s \n%s', ...
                                                        horzcat(num2str(iteration_mdf/(number_of_logs_files*length(number_of_nonempty))*100), '% completed. '), ...
                                                        time_row);
                                            else
                                                % Фиксируем конец счета
                                                t_iter_end = toc(t_iter_start);
                                                % Считаем среднее арифметическое времени, потраченого на счет:
                                                % Суммарное время:
                                                time_all = time_all + t_iter_end;
                                                % Среднее арифметическое времени
                                                t_medium = time_all/iteration_mdf;
                                                % Считаем количество оставшихся файлов
                                                number_of_remain_files = number_of_logs_files * length(number_of_nonempty) - iteration_mdf;
                                                % Считаем оставшееся время:
                                                remaining_time_sec = round(t_medium*number_of_remain_files,2);
                                                % Создаем строку времени
                                                time_row = horzcat(num2str(remaining_time_sec),' sec remains.');
                                                % Переводим секунды в минуты
                                                if remaining_time_sec > 60
                                                    remaining_time_minute = fix(remaining_time_sec/60);
                                                    remaining_time_sec = mod(remaining_time_sec,60);
                                                    time_row = horzcat(num2str(remaining_time_minute), ' min ',num2str(remaining_time_sec),' sec remains.');
                                                    % Переводим минуты в часы
                                                    if remaining_time_minute > 60
                                                        remaining_time_hours = fix(remaining_time_minute/60);
                                                        remaining_time_minute = mod(remaining_time_minute,60);
                                                        time_row = horzcat(num2str(remaining_time_hours), 'h',num2str(remaining_time_minute), ' min ',num2str(remaining_time_sec),' sec remains.');
                                                    end
                                                end
            
                                                % Продвигаем прогресс бар
                                                bar.Value = iteration_mdf/(number_of_logs_files*length(number_of_nonempty));
                                                bar.Message = sprintf('%s \n%s', ...
                                                        horzcat(num2str(iteration_mdf/(number_of_logs_files*length(number_of_nonempty))*100), '% completed. '), ...
                                                        time_row);
                                                break
                                            end                                            
                                        end    
                                        if (i_channel == length(number_of_nonempty)) && (length(number_of_nonempty) > 1)
                                            dataAll.CAN_DataFrame_DataLength = zeros(height(dataAll),1) + 8;
                                        end
                                    end

                                    % Сортируем по времени
                                    dataAll = sortrows(dataAll,"Time");

                                    if ~isempty(dataAll)
                                        % Достаем нужные нам сигналы и записываем их в итоговую таблицу Extract_signals
                                        [Extract_signals,empty_status] = Extract_signals_func(dataAll,signal_and_multiplex,canDB,DBC_path,indx_type_str);
                                    else
                                        empty_status = 1;
                                    end

                                    % Проверяем, пустой ли файл
                                    if empty_status
                                        % Если ничего нет -- значит этот файл пустой -- фикисруем это
                                        fail_file_count = fail_file_count + 1;
                                    else
                                        % Если файл не пустой -- пишем данные
                                        % После того, как мы собрали все данные с этого файла записываем их в таблицу ответов
                                        try
                                            global Extract_signals_all_files
                                            Extract_signals_all_files = outerjoin(Extract_signals_all_files,Extract_signals, ...
                                                    'Keys',[{'Time'},intersect(Extract_signals.Properties.VariableNames, ...
                                                    Extract_signals.Properties.VariableNames)],'MergeKeys',true);
                                        catch
                                            Extract_signals_all_files = Extract_signals;
                                        end
                                    end
                    
                                    clear Extract_signals
                                    % Если наш файл был мф4, то мы сдедалали их все целиком
                                    break
        
                            case ".asc" % Значит ASC
                                % Фиксируем время старта итерации
                                tStart = tic;
                                % Считываем файл
                                dataAll = ascRead( strcat(LOG_path, '\', Log_files_info(i).name), unique([(signal_and_multiplex{6,:})]));
        
                            case ".log" % Значит LOG
                                % Фиксируем время старта итерации
                                tStart = tic;
                                % Считываем файл
                                dataAll = logRead( strcat(LOG_path, '\', Log_files_info(i).name), unique([(signal_and_multiplex{6,:})]) );
        
                            case ".blf" % Значит BLF
                                % Фиксируем время старта итерации
                                tStart = tic;
                                % Применяем ascRead к сконвертированным blf-кам
                                dataAll = ascRead( strcat('C:\Temp', '\', Log_files_info(i).name), unique([(signal_and_multiplex{6,:})]) );
        
                        end
        
                        % Достаем нужные нам сигналы и записываем их в итоговую таблицу Extract_signals
                        [Extract_signals,empty_status] = Extract_signals_func(dataAll,signal_and_multiplex,canDB,DBC_path,indx_type_str);                
        
                        % Проверяем, пустой ли файл
                        if empty_status
                            % Если ничего нет -- значит этот файл пустой -- фикисруем это
                            fail_file_count = fail_file_count + 1;
                        else
                            % Если файл не пустой -- пишем данные
                            % После того, как мы собрали все данные с этого файла записываем их в таблицу ответов
                            clear('global', 'Extract_signals_all_files')
                            try
                                Extract_signals_all_files = outerjoin(Extract_signals_all_files,Extract_signals, ...
                                        'Keys',[{'Time'},intersect(Extract_signals.Properties.VariableNames, ...
                                        Extract_signals.Properties.VariableNames)],'MergeKeys',true);
                            catch
                                Extract_signals_all_files = Extract_signals;
                            end
                        end
        
                        clear Extract_signals
                        
                        % Фиксируем время финиша итерации
                        tEnd = toc(tStart);
        
                        % Если наш файл был мф4, то мы счедалали их все целиком
                        if indx_type_str == ".MF4"
                            break
                        end
        
                        iteration_time = iteration_time + round(tEnd);
                        count_time = count_time + 1;
                        medium_iteration_time = round(iteration_time/count_time);
                        % Считаем оставшееся время
                        remaining_time_sec = medium_iteration_time*(numel(Log_files_info) - i);
                        % Создаем строку времени
                        time_row = horzcat(num2str(remaining_time_sec),' sec remains.');
                        % Переводим секунды в минуты
                        if remaining_time_sec > 60
                            remaining_time_minute = fix(remaining_time_sec/60);
                            remaining_time_sec = mod(remaining_time_sec,60);
                            time_row = horzcat(num2str(remaining_time_minute), ' min ',num2str(remaining_time_sec),' sec remains.');
                            % Переводим минуты в часы
                            if remaining_time_minute > 60
                                remaining_time_hours = fix(remaining_time_minute/60);
                                remaining_time_minute = mod(remaining_time_minute,60);
                                time_row = horzcat(num2str(remaining_time_hours), 'h',num2str(remaining_time_minute), ' min ',num2str(remaining_time_sec),' sec remains.');
                            end
                        end
        
                        bar.Value = (i)/(number_of_logs_files);
                        bar.Message = sprintf('%s\n%s \n%s', ...
                                horzcat(num2str(i), ' out of ', num2str(numel(Log_files_info)), ' iterations completed. '), ...
                                horzcat('Empty files: ', num2str(fail_file_count),'.'), ...
                                time_row);
                    end
    
                    % Сохраняем файлы в базовый воркспейс и отдуда на диск
                    Unite_and_Save_Signals(Extract_signals_all_files,1,fail_file_count,number_of_logs_files,dt,app,signal_and_multiplex,Extract_signals_save_path,File_save_name,Absent_style)

                    % % Если ни в одном файле не присутствовали нужные нам сигналы
                    % if fail_file_count == number_of_logs_files
                    %     % disp('Искомых сообщений нет ни в одном файле')
                    %     % Ставим лампу на красный = все файлы пустые
                    %     app.EmptyfilestatusLamp.Color = 'r';
                    %     % Красим все сообщения и сигналы в цвет отсутствия:
                    %     % Проходим по всем сообщениям и сигналам и читаем их узлы
                    %     allnodes = findall(app.Tree_Messages_Signals,"Type","uitreenode");
                    %     % Красим все узлы в красный цвет
                    %     addStyle(app.Tree_Messages_Signals, Absent_style, 'node', allnodes);
                    % else
                    %     % Создаем результат, где время пересчитанно на фиксированный шаг            
                    %     % Проверка: нужно ли считать дискретный результат:
                    %     if dt ~= 0
                    %         Extract_signals_all_files_discrete = retime(Extract_signals_all_files,'regular','previous','SampleRate',1/dt);
                    %     end
                    % 
                    %     % Сомтрим, каких сигналов не оказалось в итоговой таблице
                    %     % Ищем их как исключающее или между множествами сигналами расшифрованными и запрашиваевыми
                    %     absent_signals = setxor(Extract_signals_all_files.Properties.VariableNames, signal_and_multiplex(2,:));
                    %     % Ищем эти сигналы в узлах нашего дерева
                    %     allnodes = findall(app.Tree_Messages_Signals,"Type","uitreenode");
                    %     logical_index = ismember({allnodes.Text}, absent_signals);
                    %     absent_signals_indices = (find(logical_index));
                    %     addStyle(app.Tree_Messages_Signals, Absent_style, 'node', allnodes(absent_signals_indices));
                    % 
                    %     % Уведомляем о завершении
                    %     bar.Message = 'Finishing';
                    %     pause(1)
                    % 
                    %     % Выбираем: считаем для дискретного или для непрерывного
                    %     if dt == 0
                    % 
                    %         % Преобразуем результат: проходим по каждому столбцу и конвертиреум его в массив
                    %         for i=1:length(Extract_signals_all_files.Properties.VariableNames)
                    %             % Создаем переменную: ее имя будет таким же, каким оно было в названии столбца, а значения столбец времени
                    %             % и ее собственный столбец. Также сразу удаляем все те строки, которые содержат NaN
                    %             assignin('base',convertCharsToStrings(Extract_signals_all_files.Properties.VariableNames(i)), ...
                    %                     rmmissing([seconds(Extract_signals_all_files(:,i).Time) table2array(Extract_signals_all_files(:,i))]) );
                    %             signal_list_needed(i) = Extract_signals_all_files.Properties.VariableNames(i);
                    %         end
                    %         clear i
                    %         % Создаем список всех переменных в базово воркспейсе
                    %         evalin('base', 'workspaceVars = who;');
                    %         % Перекидываем в базовый воркспейс список записанных сигналов
                    %         assignin('base', 'signal_list_needed_base_ws', signal_list_needed);
                    %         % В базовом воркспейсе ищем индексы нужных нам переменных в базовом воркспейсе
                    %         evalin('base', 'for iter = 1:length(signal_list_needed_base_ws) findVars(iter) = find( convertCharsToStrings(workspaceVars) == convertCharsToStrings(signal_list_needed_base_ws(iter)) );end');
                    %         % Создаем переменную пути сохранения и прекидываем ее в базовый воркспейс
                    %         % assignin('base', 'To_save_path', strcat(Extract_signals_save_path,'\LOG.mat'));
                    %         assignin('base', 'To_save_path', strcat(Extract_signals_save_path,strcat('\',File_save_name)));
                    %         % Сохраняем наши переменные, опираясь на их индексы в базовом воркспейсе
                    %         evalin('base', 'save( To_save_path,workspaceVars{findVars(:)} )' );
                    % 
                    %     else
                    % 
                    % 
                    %         % Повторяем ту же самую процедуру сохранения, но теперь берем данные из дискретного варианта
                    %         % Преобразуем результат: проходим по каждому столбцу и конвертиреум его в массив
                    %         for i=1:length(Extract_signals_all_files_discrete.Properties.VariableNames)
                    %             % Создаем переменную: ее имя будет таким же, каким оно было в названии столбца, а значения столбец времени
                    %             % и ее собственный столбец. Также сразу удаляем все те строки, которые содержат NaN
                    %             assignin('base',convertCharsToStrings(Extract_signals_all_files_discrete.Properties.VariableNames(i)), ...
                    %                     rmmissing([seconds(Extract_signals_all_files_discrete(:,i).Time) table2array(Extract_signals_all_files_discrete(:,i))]) );
                    %             signal_list_needed(i) = Extract_signals_all_files_discrete.Properties.VariableNames(i);
                    %         end
                    %         clear i
                    %         % Создаем список всех переменных в базово воркспейсе
                    %         evalin('base', 'workspaceVars = who;');
                    %         % Перекидываем в базовый воркспейс список записанных сигналов
                    %         assignin('base', 'signal_list_needed_base_ws', signal_list_needed);
                    %         % В базовом воркспейсе ищем индексы нужных нам переменных в базовом воркспейсе
                    %         evalin('base', 'for iter = 1:length(signal_list_needed_base_ws) findVars(iter) = find( convertCharsToStrings(workspaceVars) == convertCharsToStrings(signal_list_needed_base_ws(iter)) );end');
                    %         % Создаем переменную пути сохранения и прекидываем ее в базовый воркспейс
                    %         % assignin('base', 'To_save_path', strcat(Extract_signals_save_path,'\LOG_discrete.mat'));
                    %         assignin('base', 'To_save_path', strcat(Extract_signals_save_path, strcat('\',File_save_name)));
                    %         % Сохраняем наши переменные, опираясь на их индексы в базовом воркспейсе
                    %         evalin('base', 'save( To_save_path,workspaceVars{findVars(:)} )' );
                    % 
                    %     end
                    % 
                    %     % Чистим за собой то, что создали в базовом воркспейсе
                    %     evalin('base', 'clearvars(workspaceVars{findVars(:)})')
                    %     evalin('base', 'clearvars iter')
                    %     evalin('base', 'clearvars findVars')
                    %     evalin('base', 'clearvars signal_list_needed_base_ws')
                    %     evalin('base', 'clearvars To_save_path')
                    %     evalin('base', 'clearvars workspaceVars')
                    % end
        
                    % Удаляем временные файлы: обработанные или финализированные логи
                    % Включаем опцию удаления мимо корзины
                    recycle('off');
                    switch indx_type_str
                            case ".MF4" % Значит MF4
                                % Чистим датастор для корректоного удаления
                                clear dataAll_datastore
                                % Ищем все финализированные логи для удаления
                                fin_Log_info = dir(append(LOG_path,'\fin_*.MF4'));
                            case ".asc" % Значит ASC
                                % Ищем все обработанные (без шапки) логи для удаления
                                fin_Log_info = dir(append(LOG_path,'\fin_*.asc'));
                            case ".log" % Значит LOG
                                % Ищем все обработанные (без шапки) логи для удаления
                                fin_Log_info = dir(append(LOG_path,'\fin_*.log'));
                            case ".blf" % Значит BLF
                                % Ищем все blf-ки во временном фолдере (ОНИ УЖЕ УДАЛЕНЫ, ПОСЛЕ СПЛИТА)
                                % fin_Log_info_temp = dir(append('C:\Temp','\fin_*.blf'));
                                % % Удаляем файлы из временного фолдера
                                % for i=1:numel(fin_Log_info_temp)
                                %     delete(append('C:\Temp','\',fin_Log_info_temp(i).name));
                                % end
        
                                % Ищем все blf-ки в текущем фолдере
                                fin_Log_info = dir(append(LOG_path,'\fin_*.blf'));
        
                                % Ищем все asc-шки во временном фолдере
                                fin_Log_info_temp_txt  = dir(append('C:\Temp','\fin_*.txt'));
                                % Удаляем blf-ки из временного фолдера
                                for i=1:numel(fin_Log_info_temp_txt)
                                    delete(append('C:\Temp','\',fin_Log_info_temp_txt(i).name));
                                end
                    end            
                    % Удаляем то,что нашли
                    for i=1:numel(fin_Log_info)
                        delete(append(LOG_path,'\',fin_Log_info(i).name));
                    end
                    % Выключаем опцию удаления мимо корзины
                    recycle('on');
                end
                % Если работаем в параллельном режиме
                if strcmp(parallel_mode,'On')
                    % Читаем информацию, о всех LOG файлах, которые лежат в выбранной папке
                    % и перобразуем их слогласно выбранному типу
                    % Создаем обычный массив имен файлов
                    Log_files_info = convertCharsToStrings(app.LOGs_files);
                    [~,~,indx_type_str] = fileparts(app.LOGs_files{1});
                    switch indx_type_str        
                        case ".MF4" % Значит MF4
                            % Проходим по всем MDF файлам и смотрим законченный ли он. Если нет, то заканчиваем.
                                
                            % Create DataQueue and listener
                            Listener_par_finalize = parallel.pool.DataQueue;
                            % Вызываем функцию объединения полученных таблиц
                            afterEach(Listener_par_finalize,@Par_mdfFinalize);
                            % Проходим по всем MDF файлам и смотрим законченный ли он. Если нет, то заканчиваем.
                            parfor i=1:numel(Log_files_info)
                                % Заканчиваем незаконченный лог и создаем его копию с припиской "_fin" на конце
                                % Если у файла нет приписки "fin_" в начале, то значит мы его не заканчивали, тогда закончим его
                                send(Listener_par_finalize,{LOG_path,Log_files_info(i)});
                            end

                            % Ставим барьер = ждем, когда все ядра завершат обработку
                            spmd
                                spmdBarrier;
                            end
                            % Читаем информацию, о всех LOG файлах, которые лежат в Log_files_info папке, 
                            % теперь к ним могли добавиться законченные или обработанные, значит их общее количество увеличилось
                            Log_files_info = dir(append(LOG_path,'\fin_*.MF4'));
                            number_of_logs_files = numel(app.LOGs_files);
                    end
        
                    % Инициализируем переменную, которая будет считать в скольки файлах отстутвуют ВСЕ нужные нам сигналы
                    fail_file_count = 0;        
 
                    % В зависимости от выбраннго типа применяем функции чтения и прикладываем DBC
                    [~,~,indx_type_str] = fileparts(app.LOGs_files{1});
                    switch indx_type_str
                        case ".MF4"  % Значит MF4
                            % Создаем массив имен файлов, которые нам нужны
                            Log_files_name = strcat(LOG_path, '\', {Log_files_info.name});
                            % Считываем все финализированные MF4 в один датасет
                            dataAll_datastore = mdfDatastore( Log_files_name(1) );
                            % Находим индексы непустых Channel Group
                            number_of_nonempty = find(dataAll_datastore.ChannelGroups.DataSize>0);
                            % Создаем шаг чтения
                            dataAll_datastore.ReadSize = 'file';
                            % Если в списке присутствуют файлы больших размеров, то читаем не пофайлово а по количественно
                            for i_size = 1:numel(Log_files_info)
                                if Log_files_info(i_size).bytes > 100000*1024
                                    dataAll_datastore.ReadSize = '50000';
                                    break
                                end
                            end
                            % Инициализируем массив расписания по каналам и по файлам
                            clear i_table
                            i_table(1,:) = repmat([1:length(number_of_nonempty)],1,number_of_logs_files);
                            i_table(2,:) = sort(repmat([1:number_of_logs_files],1,length(number_of_nonempty)));

                            % Инициализируем прогресс бар для паралелльного расчета
                            w = waitbar(0,'Please wait ...','WindowStyle','modal');
                            % Create DataQueue and listener
                            D = parallel.pool.DataQueue;
                            % Вызываем функцию объединения полученных таблиц
                            afterEach(D,@Signals_table_join);
                            % Инициализируем вейт бар
                            Signals_table_join({-1,w,length(number_of_nonempty)*number_of_logs_files,timetable.empty});
                          
                            parfor i_channel_file=1:length(number_of_nonempty)*number_of_logs_files
                                % Стартуем счет врмени
                                time_par_start = tic;
                                % Считываем какой канал для чтения и какой файл
                                i_channel = i_table(1,i_channel_file);
                                i_file = i_table(2,i_channel_file);
                                % Считываем все финализированные MF4 в один датасет
                                dataAll_datastore = mdfDatastore( Log_files_name( i_file ) );
                                % Выбираем канал для чтения
                                dataAll_datastore.SelectedChannelGroupNumber = number_of_nonempty(i_channel);
                                % Считываем дату пошагово
                                timetable_data= read(dataAll_datastore);
                                % Если таблица непустая, то обрабатываем ее, иначе скипаем и помечаем, что она пустая
                                if ~isempty(timetable_data)
                                    % Проставляем 8 в графе длины
                                    if (length(number_of_nonempty) > 1)
                                        timetable_data.CAN_DataFrame_DataLength = zeros(height(timetable_data),1) + 8;
                                    end
                                    % Удаляем из записи все те сообщения, ID которых нет в списке нужных нам
                                    logical_count = ismember( uint32(timetable_data.CAN_DataFrame_ID), unique([(signal_and_multiplex{6,:})]) );
                                    % Берем индексы этих элементов
                                    indexes = find(logical_count);
                                    % Убераем ненужные сообщения
                                    timetable_data = timetable_data(indexes,:);
                                    % % Удаляем из таблицы все остальные индексы, т.е. удаляем из таблицы те сообщения, которые нам не нужны
                                    % dataAll = [dataAll;timetable_data]; 
                                    % % Сортируем по времени
                                    % dataAll = sortrows(dataAll,"Time");
                                end
                                % Достаем нужные нам сигналы и записываем их в итоговую таблицу Extract_signals
                                [Extract_signals,empty_status] = Extract_signals_func(timetable_data,signal_and_multiplex,canDB,DBC_path,indx_type_str);
                                % Заканчиваем счет времени
                                time_par_stop = toc(time_par_start);
                                % Проверяем, пустой ли файл
                                if empty_status
                                    % Если ничего нет -- значит, этот файл пустой -- фикисруем это
                                    fail_file_count = fail_file_count + 1;
                                    % Отправляем данные с воркера на клиент
                                    send(D,{time_par_stop,timetable.empty});
                                else
                                    % Отправляем данные с воркера на клиент
                                    send(D,{time_par_stop,Extract_signals});
                                end
                            end
                            global Extract_signals_all_files
                            % Очищаем прогресс бар
                            delete(w);

                            % Сохраняем файлы в базовый воркспейс и отдуда на диск
                            Unite_and_Save_Signals(Extract_signals_all_files,number_of_nonempty,fail_file_count,number_of_logs_files,dt,app,signal_and_multiplex,Extract_signals_save_path,File_save_name,Absent_style)
                            % Удаляем глобальную переменную
                            clear('global', 'Extract_signals_all_files')

                            % Уведомляем о завершении
                            bar.Message = 'Finishing';
                            pause(1)
                            % Удаляем временные файлы: обработанные или финализированные логи
                            % Включаем опцию удаления мимо корзины
                            recycle('off');
                            switch indx_type_str
                                    case ".MF4"  % Значит MF4
                                        % Чистим датастор, чтобы удалить файлы
                                        clear dataAll_datastore
                                        % Ищем все финализированные логи для удаления
                                        fin_Log_info = dir(append(LOG_path,'\fin_*.MF4'));
                            end            
                            % Удаляем то,что нашли
                            for i=1:numel(fin_Log_info)
                                delete(append(LOG_path,'\',fin_Log_info(i).name));
                            end
                            % Выключаем опцию удаления мимо корзины
                            recycle('on');
                    end
                end
            else
                % Считываем корневую директорию
                Core_directory = app.LOGpathEditField.Value;
                % Получаем расположение всех файлов в данной директории
                All_files_path = dir(strcat(Core_directory,'\**\*.MF4'));
                % Удаляем из этого списка файлы, которые имеют нулевой вес (обработка таких случаев вызывает ошибки, удобнее избавиться от них в начале)
                All_files_path(find(cell2mat({All_files_path.bytes}) == 0)) = [];
                % Находим уникальные фолдеры
                [Unique_folders,~,index_of_same_folder] = unique({All_files_path.folder});

                % ------------------------- Разделение для запуска на нескольких компах --------------------------
                % Общая доля
                All_parts = 5;                
                % Индексы фолдеров, разбытых по долям
                Part_folder_indexes = [1:1:All_parts]*floor(length(Unique_folders)/All_parts);
                % Прибавляем последнему элементу остаток от деления
                Part_folder_indexes(end) = Part_folder_indexes(end) + mod(length(Unique_folders),All_parts);

                % Мои доли из общего количества
                My_parts = [1,2];
                % ------------------------- Разделение для запуска на нескольких компах --------------------------

                % Проходим по каждому фолдеру
                % Показываем на прогресс баре, что мы начали
                bar.Message = 'Start';
                % Считаем количество оставшихся файлов
                number_of_remain_files = length(All_files_path);
                for i_folder=1:length(Unique_folders)
                       
                    % ------------------------- Разделение для запуска на нескольких компах --------------------------
                    if (i_folder > Part_folder_indexes(max(My_parts))) || (i_folder < Part_folder_indexes(min(My_parts)) - floor(length(Unique_folders)/All_parts))
                        continue
                    end
                    % ------------------------- Разделение для запуска на нескольких компах --------------------------

                    % Считываем информации о фолдере нужных логов
                    LOG_path = Unique_folders{i_folder};
                    % Записываем путь сохранения
                    % Если не указали пути сохранения, сохраняем в свою папку
                    if isempty(app.SavepathEditField.Value)
                        % Изымаем последнее слово в пути сохранения
                        Extract_signals_save_path = LOG_path;
                    else
                        % Если указали, то сохраняем туда
                        Extract_signals_save_path = app.SavepathEditField.Value;
                    end
                    % Записываем имя сохранения:
                    % Ищем позицию последнего составляющего в пути сохранения
                    Last_slash_position = strfind(LOG_path,'\');        

                    % Сохраняем, как "имя папки", при этом удаляем все точки, чтобы не портить тип сохраненных данных
                    % File_save_name = strrep(LOG_path(Last_slash_position(end)+1:end),'.','_');
                    % Сохраняем как "имя надпапки_имя папки", при этом удаляем все точки, чтобы не портить тип сохраненных данных
                    File_save_name = strrep(strrep(LOG_path(Last_slash_position(end-1)+1:end),'\','_'),'.','_');

                    % Собираем информацию о всех файлах, которые конвертируются
                    app.LOGs_files = {All_files_path(find(index_of_same_folder == i_folder)).name};
                    % Если работаем в последовательном режиме
                    if strcmp(parallel_mode,'Off')
                        % Читаем информацию, о всех LOG файлах, которые лежат в выбранной папке
                        % и перобразуем их слогласно выбранному типу
                        % Создаем обычный массив имен файлов
                        Log_files_info = convertCharsToStrings(app.LOGs_files);
                        [~,~,indx_type_str] = fileparts(app.LOGs_files{1});
                        switch indx_type_str
            
                            case ".MF4" % Значит MF4
                                % Проходим по всем MDF файлам и смотрим законченный ли он. Если нет, то заканчиваем.
                                for i=1:numel(Log_files_info)
                                    % Заканчиваем незаконченный лог и создаем его копию с припиской "_fin" на конце
                                    % Если у файла нет приписки "fin_" в начале, то значит мы его не заканчивали, тогда закончим его
                                    if isempty(strfind(Log_files_info(i),'fin_'))
                                        try
                                            finalizedPath = mdfFinalize(strcat(LOG_path, '\', Log_files_info(i)), strcat(LOG_path, '\', "fin_", Log_files_info(i)));
                                        catch ME
                                            disp(ME.message)
                                        end
                                    end
                                end
                                % Читаем информацию, о всех LOG файлах, которые лежат в Log_files_info папке, 
                                % теперь к ним могли добавиться законченные или обработанные, значит их общее количество увеличилось
                                Log_files_info = dir(append(LOG_path,'\fin_*.MF4'));
                                number_of_logs_files = numel(app.LOGs_files);
            
                            case ".asc" % Значит ASC
                                % Проходим по всем ASC файлам 
                                for i=1:numel(Log_files_info)
                                    % Проходя по каждому ASC файлу убираем его шапку, чтобы его можно было прочитать
                                    fid = fopen(strcat(LOG_path, '\', Log_files_info(i)), 'r') ;        % Open source file.
                                    buffer = fread(fid, Inf) ;                                          % Read rest of the file.
                                    fclose(fid);
                                    % Теперь создадим копию без шапки, с которой будем работать
                                    fid = fopen(strcat(LOG_path, '\', "fin_", Log_files_info(i)), 'w')  ;  % Open destination file.
                                    fwrite(fid, buffer) ;                                                  % Save to file.
                                    fclose(fid) ;
                                end
                                % Очищаем буфер считанного файла
                                clear buffer
                                % Читаем информацию, о всех LOG файлах, которые лежат в Log_files_info папке, 
                                % теперь к ним могли добавиться законченные или обработанные, значит их общее количество увеличилось
                                Log_files_info = dir(append(LOG_path,'\fin_*.asc'));
                                number_of_logs_files = numel(app.LOGs_files);
            
                            case ".log" % Значит LOG
                                % Проходим по всем LOG файлам 
                                for i=1:numel(Log_files_info)
                                    % Проходя по каждому LOG файлу убираем его шапку, чтобы его можно было прочитать
                                    fid = fopen(strcat(LOG_path, '\', Log_files_info(i)), 'r') ;        % Open source file.
                                    buffer = fread(fid, Inf) ;                                          % Read rest of the file.
                                    fclose(fid);
                                    
                                    fid = fopen(strcat(LOG_path, '\', "fin_", Log_files_info(i)), 'w')  ;  % Open destination file.
                                    fwrite(fid, buffer) ;                                                  % Save to file.
                                    fclose(fid) ;
                                end
                                % Очищаем буфер считанного файла
                                clear buffer
                                % Читаем информацию, о всех LOG файлах, которые лежат в Log_files_info папке, 
                                % теперь к ним могли добавиться законченные или обработанные, значит их общее количество увеличилось
                                Log_files_info = dir(append(LOG_path,'\fin_*.log'));
                                number_of_logs_files = numel(app.LOGs_files);
            
                            case ".blf" % Значит BLF
                                % Проходим по все blf файлам и копируем их в папку 'C:\Temp', чтобы избежать проблем с кириллицей
                                for i=1:numel(Log_files_info)
                                    copyfile( strcat(LOG_path, '\', Log_files_info(i)), strcat(LOG_path, '\', "fin_", Log_files_info(i)) );
                                    copyfile( strcat(LOG_path, '\', "fin_", Log_files_info(i)), 'C:\Temp' );
                                end
                                % Создаем массив данных о blf-ках во временной директории
                                Log_files_copy_folder = dir(append('C:\Temp','\*.blf'));
                                % Разбиваем blf-ки во временной директории на ASC-шки
                                % Сообщаем о том, что началось разбиение
                                bar.Message = 'Splitting .blf files';
                                % В качестве вывода получаем количество таких файлов
                                count_of_asc = blfSplitToAsc(unique([(signal_and_multiplex{6,:})]), canDB, 'C:\Temp', app.LOGs_files, File_size);
                                % Удаляем финализированные blf во временной директории
                                recycle("off")
                                % Ищем blf во временной директории
                                fin_Log_info_temp = dir(append('C:\Temp','\fin_*.blf'));
                                % Удаляем их из временного фолдера
                                for i=1:numel(fin_Log_info_temp)
                                    delete(append('C:\Temp','\',fin_Log_info_temp(i).name));
                                end
                                recycle("on")
                                % Подготовим значения Log_files_info и app.LOGs_files для обработки через asc
                                Log_files_info = dir(append('C:\Temp','\fin_*.txt'));
                                number_of_logs_files = count_of_asc;
                        end
            
                        % Инициализируем переменную, которая будет считать в скольки файлах отстутвуют ВСЕ нужные нам сигналы
                        fail_file_count = 0;           
                        
                        % Инициализируем переменные для подсчета времени
                        iteration_time = 0;
                        count_time = 0;
                        for i = 1:numel(Log_files_info)
                            % В зависимости от выбраннго типа применяем функции чтения и прикладываем DBC
                            switch indx_type_str
                                case ".MF4" % Значит MF4
                                    % Создаем массив имен файлов, которые нам нужны
                                    Log_files_name = strcat(LOG_path, '\', {Log_files_info.name});
                                    % Считываем все финализированные MF4 в один датасет
                                    dataAll_datastore = mdfDatastore( Log_files_name );
                                    % Находим индексы непустых Channel Group
                                    number_of_nonempty = find(dataAll_datastore.ChannelGroups.DataSize>0);
                                    % Создаем шаг чтения
                                    dataAll_datastore.ReadSize = 'file';
                                    % Если в списке присутствуют файлы больших размеров, то читаем не пофайлово а по количественно
                                    for i_size=1:numel(Log_files_info)
                                        if Log_files_info(i_size).bytes > 100000*1024
                                            dataAll_datastore.ReadSize = '50000';
                                            break
                                        end
                                    end
                                    if i_folder == 1
                                        % Инициализируем счетчика итераций
                                        iteration_mdf = 0;
                                        % Инициализируем общее время
                                        time_all = 0;
                                    end
                                    % Обнуляем результирующую таблицу
                                    dataAll = timetable.empty;
                                    for i_channel=1:length(number_of_nonempty)
                                        % Выбираем канал для чтения
                                        dataAll_datastore.SelectedChannelGroupNumber = number_of_nonempty(i_channel);
                                        % Ресетаем датастор
                                        reset(dataAll_datastore);
                                        % Считываем дату пошагово
                                        while hasdata(dataAll_datastore)
                                            % Фиксируем начала счета
                                            t_iter_start = tic;
                                            % Увеличиваем счтетчик итераций
                                            iteration_mdf = iteration_mdf + 1;
                                            % timetable_data = readall(dataAll,"UseParallel",true);
                                            timetable_data = read(dataAll_datastore);
                                            % Если таблица непустая, то обрабатываем ее, если она пустая, значит на самом деле среди number_of_nonempty имеются лишние каналы, 
                                            % которых нет в некоторых файлах, значит мы не пытаемся обработать пустую таблицу, а переходим к следующему каналу
                                            if ~isempty(timetable_data)
                                                % Удаляем из записи все те сообщения, ID которых нет в списке нужных нам
                                                logical_count = ismember( uint32(timetable_data.CAN_DataFrame_ID), unique([(signal_and_multiplex{6,:})]) );
                                                % Берем индексы этих элементов
                                                indexes = find(logical_count);
                                                % Удаляем из таблицы все остальные индексы, т.е. удаляем из таблицы те сообщения, которые нам не нужны
                                                timetable_data = timetable_data(indexes,:);
                                                % Добавляем полученный результат к старым данным
                                                dataAll = [dataAll;timetable_data];

                                                % Фиксируем конец счета
                                                t_iter_end = toc(t_iter_start);
                                                % Считаем среднее арифметическое времени, потраченого на счет:
                                                % Суммарное время:
                                                time_all = time_all + t_iter_end;
                                                % Среднее арифметическое времени
                                                t_medium = time_all/iteration_mdf;
                                                % Считаем оставшееся время:
                                                remaining_time_sec = round(t_medium * (number_of_remain_files * length(number_of_nonempty) - iteration_mdf),2);
                                                % Создаем строку времени
                                                time_row = horzcat(num2str(remaining_time_sec),' sec remains.');
                                                % Переводим секунды в минуты
                                                if remaining_time_sec > 60
                                                    remaining_time_minute = fix(remaining_time_sec/60);
                                                    remaining_time_sec = mod(remaining_time_sec,60);
                                                    time_row = horzcat(num2str(remaining_time_minute), ' min ',num2str(remaining_time_sec),' sec remains.');
                                                    % Переводим минуты в часы
                                                    if remaining_time_minute > 60
                                                        remaining_time_hours = fix(remaining_time_minute/60);
                                                        remaining_time_minute = mod(remaining_time_minute,60);
                                                        time_row = horzcat(num2str(remaining_time_hours), 'h',num2str(remaining_time_minute), ' min ',num2str(remaining_time_sec),' sec remains.');
                                                    end
                                                end
    
                                                % Продвигаем прогресс бар
                                                bar.Value = iteration_mdf/(length(All_files_path)*length(number_of_nonempty));
                                                bar.Message = sprintf('%s \n%s', ...
                                                        horzcat(num2str(round(iteration_mdf/(length(All_files_path)*length(number_of_nonempty)),2)*100), '% completed. '), ...
                                                        time_row);
                                            else
                                                % Фиксируем конец счета
                                                t_iter_end = toc(t_iter_start);
                                                % Считаем среднее арифметическое времени, потраченого на счет:
                                                % Суммарное время:
                                                time_all = time_all + t_iter_end;
                                                % Среднее арифметическое времени
                                                t_medium = time_all/iteration_mdf;
                                                % Считаем оставшееся время:
                                                remaining_time_sec = round(t_medium * (number_of_remain_files * length(number_of_nonempty) - iteration_mdf),2);
                                                % Создаем строку времени
                                                time_row = horzcat(num2str(remaining_time_sec),' sec remains.');
                                                % Переводим секунды в минуты
                                                if remaining_time_sec > 60
                                                    remaining_time_minute = fix(remaining_time_sec/60);
                                                    remaining_time_sec = mod(remaining_time_sec,60);
                                                    time_row = horzcat(num2str(remaining_time_minute), ' min ',num2str(remaining_time_sec),' sec remains.');
                                                    % Переводим минуты в часы
                                                    if remaining_time_minute > 60
                                                        remaining_time_hours = fix(remaining_time_minute/60);
                                                        remaining_time_minute = mod(remaining_time_minute,60);
                                                        time_row = horzcat(num2str(remaining_time_hours), 'h',num2str(remaining_time_minute), ' min ',num2str(remaining_time_sec),' sec remains.');
                                                    end
                                                end
    
                                                % Продвигаем прогресс бар
                                                bar.Value = iteration_mdf/(length(All_files_path)*length(number_of_nonempty));
                                                bar.Message = sprintf('%s \n%s', ...
                                                        horzcat(num2str(round(iteration_mdf/(length(All_files_path)*length(number_of_nonempty)),2)*100), '% completed. '), ...
                                                        time_row);
                                                break
                                            end                                            
                                        end    
                                        if (i_channel == length(number_of_nonempty)) && (length(number_of_nonempty) > 1)
                                            dataAll.CAN_DataFrame_DataLength = zeros(height(dataAll),1) + 8;
                                        end
                                    end

                                    % Сортируем по времени
                                    dataAll = sortrows(dataAll,"Time");

                                    % Достаем нужные нам сигналы и записываем их в итоговую таблицу Extract_signals
                                    [Extract_signals,empty_status] = Extract_signals_func(dataAll,signal_and_multiplex,canDB,DBC_path,indx_type_str);

                                    % Проверяем, пустой ли файл
                                    if empty_status
                                        % Если ничего нет -- значит этот файл пустой -- фикисруем это
                                        fail_file_count = fail_file_count + 1;
                                    else
                                        % Если файл не пустой -- пишем данные
                                        % После того, как мы собрали все данные с этого файла записываем их в таблицу ответов
                                        clear('global', 'Extract_signals_all_files')
                                        try
                                            Extract_signals_all_files = outerjoin(Extract_signals_all_files,Extract_signals, ...
                                                    'Keys',[{'Time'},intersect(Extract_signals.Properties.VariableNames, ...
                                                    Extract_signals.Properties.VariableNames)],'MergeKeys',true);
                                        catch
                                            Extract_signals_all_files = Extract_signals;
                                        end
                                    end
                    
                                    clear Extract_signals
                                    % Если наш файл был MF4, то мы сдедалали их все целиком
                                    break

                                case ".asc" % Значит ASC
                                    % Фиксируем время старта итерации
                                    tStart = tic;
                                    % Считываем файл
                                    dataAll = ascRead( strcat(LOG_path, '\', Log_files_info(i).name), unique([(signal_and_multiplex{6,:})]));
            
                                case ".log" % Значит LOG
                                    % Фиксируем время старта итерации
                                    tStart = tic;
                                    % Считываем файл
                                    dataAll = logRead( strcat(LOG_path, '\', Log_files_info(i).name), unique([(signal_and_multiplex{6,:})]) );
            
                                case ".blf" % Значит BLF
                                    % Фиксируем время старта итерации
                                    tStart = tic;
                                    % Применяем ascRead к сконвертированным blf-кам
                                    dataAll = ascRead( strcat('C:\Temp', '\', Log_files_info(i).name), unique([(signal_and_multiplex{6,:})]) );
            
                            end
            
                            % Достаем нужные нам сигналы и записываем их в итоговую таблицу Extract_signals
                            [Extract_signals,empty_status] = Extract_signals_func(dataAll,signal_and_multiplex,canDB,DBC_path,indx_type_str);
            
                            % Проверяем, пустой ли файл
                            if empty_status
                                % Если ничего нет -- значит этот файл пустой -- фикисруем это
                                fail_file_count = fail_file_count + 1;
                            else
                                % Если файл не пустой -- пишем данные
                                % После того, как мы собрали все данные с этого файла записываем их в таблицу ответов
                                try
                                    Extract_signals_all_files = outerjoin(Extract_signals_all_files,Extract_signals, ...
                                            'Keys',[{'Time'},intersect(Extract_signals.Properties.VariableNames, ...
                                            Extract_signals.Properties.VariableNames)],'MergeKeys',true);
                                catch
                                    Extract_signals_all_files = Extract_signals;
                                end
                            end
            
                            clear Extract_signals
                            
                            % Фиксируем время финиша итерации
                            tEnd = toc(tStart);
            
                            % Если наш файл был мф4, то мы счедалали их все целиком
                            if indx_type_str == ".MF4"
                                break
                            end
            
                            iteration_time = iteration_time + round(tEnd);
                            count_time = count_time + 1;
                            medium_iteration_time = round(iteration_time/count_time);
                            % Считаем оставшееся время
                            remaining_time_sec = medium_iteration_time*(numel(Log_files_info) - i)*(length(Unique_folders) - i_folder);
                            % Создаем строку времени
                            time_row = horzcat(num2str(remaining_time_sec),' sec remains.');
                            % Переводим секунды в минуты
                            if remaining_time_sec > 60
                                remaining_time_minute = fix(remaining_time_sec/60);
                                remaining_time_sec = mod(remaining_time_sec,60);
                                time_row = horzcat(num2str(remaining_time_minute), ' min ',num2str(remaining_time_sec),' sec remains.');
                                % Переводим минуты в часы
                                if remaining_time_minute > 60
                                    remaining_time_hours = fix(remaining_time_minute/60);
                                    remaining_time_minute = mod(remaining_time_minute,60);
                                    time_row = horzcat(num2str(remaining_time_hours), 'h',num2str(remaining_time_minute), ' min ',num2str(remaining_time_sec),' sec remains.');
                                end
                            end
            
                            bar.Value = (i)/(number_of_logs_files) * i_folder/length(Unique_folders);
                            bar.Message = sprintf('%s\n%s \n%s', ...
                                    horzcat(num2str(i), ' out of ', num2str(number_of_logs_files), ' iterations completed. '), ...
                                    horzcat('Empty files: ', num2str(fail_file_count),'.'), ...
                                    time_row);
                        end 

                        % Сохраняем файлы в базовый воркспейс и отдуда на диск
                        Unite_and_Save_Signals(Extract_signals_all_files,1,fail_file_count,number_of_logs_files,dt,app,signal_and_multiplex,Extract_signals_save_path,File_save_name,Absent_style)
                        % Удаляем глобальную переменную
                        clear Extract_signals_all_files
        
                        % % Если ни в одном файле не присутствовали нужные нам сигналы
                        % if fail_file_count == number_of_logs_files
                        %     % disp('Искомых сообщений нет ни в одном файле')
                        %     % Ставим лампу на красный = все файлы пустые
                        %     app.EmptyfilestatusLamp.Color = 'r';
                        %     % Красим все сообщения и сигналы в цвет отсутствия:
                        %     % Проходим по всем сообщениям и сигналам и читаем их узлы
                        %     allnodes = findall(app.Tree_Messages_Signals,"Type","uitreenode");
                        %     % Красим все узлы в красный цвет
                        %     addStyle(app.Tree_Messages_Signals, Absent_style, 'node', allnodes);
                        % else
                        %     % Создаем результат, где время пересчитанно на фиксированный шаг            
                        %     % Проверка: нужно ли считать дискретный результат:
                        %     if dt ~= 0
                        %         Extract_signals_all_files_discrete = retime(Extract_signals_all_files,'regular','previous','SampleRate',1/dt);
                        %     end
                        % 
                        %     % Сомтрим, каких сигналов не оказалось в итоговой таблице
                        %     % Ищем их как исключающее или между множествами сигналами расшифрованными и запрашиваевыми
                        %     absent_signals = setxor(Extract_signals_all_files.Properties.VariableNames, signal_and_multiplex(2,:));
                        %     % Ищем эти сигналы в узлах нашего дерева
                        %     allnodes = findall(app.Tree_Messages_Signals,"Type","uitreenode");
                        %     logical_index = ismember({allnodes.Text}, absent_signals);
                        %     absent_signals_indices = (find(logical_index));
                        %     addStyle(app.Tree_Messages_Signals, Absent_style, 'node', allnodes(absent_signals_indices));
                        % 
                        %     % Выбираем: считаем для дискретного или для непрерывного
                        %     if dt == 0
                        % 
                        %         clear signal_list_needed
                        %         % Преобразуем результат: проходим по каждому столбцу и конвертиреум его в массив
                        %         for i=1:length(Extract_signals_all_files.Properties.VariableNames)
                        %             % Создаем переменную: ее имя будет таким же, каким оно было в названии столбца, а значения столбец времени
                        %             % и ее собственный столбец. Также сразу удаляем все те строки, которые содержат NaN
                        %             assignin('base',convertCharsToStrings(Extract_signals_all_files.Properties.VariableNames(i)), ...
                        %                     rmmissing([seconds(Extract_signals_all_files(:,i).Time) table2array(Extract_signals_all_files(:,i))]) );
                        %             signal_list_needed(i) = Extract_signals_all_files.Properties.VariableNames(i);
                        %         end
                        %         clear i
                        %         % Создаем список всех переменных в базово воркспейсе
                        %         evalin('base', 'workspaceVars = who;');
                        %         % Перекидываем в базовый воркспейс список записанных сигналов
                        %         assignin('base', 'signal_list_needed_base_ws', signal_list_needed);
                        %         % В базовом воркспейсе ищем индексы нужных нам переменных в базовом воркспейсе
                        %         evalin('base', 'for iter = 1:length(signal_list_needed_base_ws) findVars(iter) = find( convertCharsToStrings(workspaceVars) == convertCharsToStrings(signal_list_needed_base_ws(iter)) );end');
                        %         % Создаем переменную пути сохранения и прекидываем ее в базовый воркспейс
                        %         % assignin('base', 'To_save_path', strcat(Extract_signals_save_path,'\LOG.mat'));
                        %         assignin('base', 'To_save_path', strcat(Extract_signals_save_path,strcat('\',File_save_name)));
                        %         % Сохраняем наши переменные, опираясь на их индексы в базовом воркспейсе
                        %         evalin('base', 'save( To_save_path,workspaceVars{findVars(:)} )' );
                        % 
                        %     else
                        % 
                        % 
                        %         % Повторяем ту же самую процедуру сохранения, но теперь берем данные из дискретного варианта
                        %         clear signal_list_needed
                        %         % Преобразуем результат: проходим по каждому столбцу и конвертиреум его в массив
                        %         for i=1:length(Extract_signals_all_files_discrete.Properties.VariableNames)
                        %             % Создаем переменную: ее имя будет таким же, каким оно было в названии столбца, а значения столбец времени
                        %             % и ее собственный столбец. Также сразу удаляем все те строки, которые содержат NaN
                        %             assignin('base',convertCharsToStrings(Extract_signals_all_files_discrete.Properties.VariableNames(i)), ...
                        %                     rmmissing([seconds(Extract_signals_all_files_discrete(:,i).Time) table2array(Extract_signals_all_files_discrete(:,i))]) );
                        %             signal_list_needed(i) = Extract_signals_all_files_discrete.Properties.VariableNames(i);
                        %         end
                        %         clear i
                        %         % Создаем список всех переменных в базово воркспейсе
                        %         evalin('base', 'workspaceVars = who;');
                        %         % Перекидываем в базовый воркспейс список записанных сигналов
                        %         assignin('base', 'signal_list_needed_base_ws', signal_list_needed);
                        %         % В базовом воркспейсе ищем индексы нужных нам переменных в базовом воркспейсе
                        %         evalin('base', 'for iter = 1:length(signal_list_needed_base_ws) findVars(iter) = find( convertCharsToStrings(workspaceVars) == convertCharsToStrings(signal_list_needed_base_ws(iter)) );end');
                        %         % Создаем переменную пути сохранения и прекидываем ее в базовый воркспейс
                        %         % assignin('base', 'To_save_path', strcat(Extract_signals_save_path,'\LOG_discrete.mat'));
                        %         assignin('base', 'To_save_path', strcat(Extract_signals_save_path, strcat('\',File_save_name)));
                        %         % Сохраняем наши переменные, опираясь на их индексы в базовом воркспейсе
                        %         evalin('base', 'save( To_save_path,workspaceVars{findVars(:)} )' );
                        % 
                        %     end
                        % 
                        %     % Чистим за собой то, что создали в базовом воркспейсе
                        %     evalin('base', 'clearvars(workspaceVars{findVars(:)})')
                        %     evalin('base', 'clearvars iter')
                        %     evalin('base', 'clearvars findVars')
                        %     evalin('base', 'clearvars signal_list_needed_base_ws')
                        %     evalin('base', 'clearvars To_save_path')
                        %     evalin('base', 'clearvars workspaceVars')
                        % end
            
                        % Удаляем временные файлы: обработанные или финализированные логи
                        % Включаем опцию удаления мимо корзины
                        recycle('off');
                        switch indx_type_str
                                case ".MF4" % Значит MF4
                                    % Чистим датастор, чтобы удалить файлы
                                    clear dataAll_datastore
                                    % Ищем все финализированные логи для удаления
                                    fin_Log_info = dir(append(LOG_path,'\fin_*.MF4'));
                                case ".asc" % Значит ASC
                                    % Ищем все обработанные (без шапки) логи для удаления
                                    fin_Log_info = dir(append(LOG_path,'\fin_*.asc'));
                                case ".log" % Значит LOG
                                    % Ищем все обработанные (без шапки) логи для удаления
                                    fin_Log_info = dir(append(LOG_path,'\fin_*.log'));
                                case ".blf" % Значит BLF
                                    % Ищем все blf-ки во временном фолдере (ОНИ УЖЕ УДАЛЕНЫ, ПОСЛЕ СПЛИТА)
                                    % fin_Log_info_temp = dir(append('C:\Temp','\fin_*.blf'));
                                    % % Удаляем файлы из временного фолдера
                                    % for i=1:numel(fin_Log_info_temp)
                                    %     delete(append('C:\Temp','\',fin_Log_info_temp(i).name));
                                    % end
            
                                    % Ищем все blf-ки в текущем фолдере
                                    fin_Log_info = dir(append(LOG_path,'\fin_*.blf'));
            
                                    % Ищем все asc-шки во временном фолдере
                                    fin_Log_info_temp_txt  = dir(append('C:\Temp','\fin_*.txt'));
                                    % Удаляем blf-ки из временного фолдера
                                    for i=1:numel(fin_Log_info_temp_txt)
                                        delete(append('C:\Temp','\',fin_Log_info_temp_txt(i).name));
                                    end
                        end
                        fclose('all');
                        % Удаляем то,что нашли
                        for i=1:numel(fin_Log_info)
                            delete(append(LOG_path,'\',fin_Log_info(i).name));
                        end
                        % Выключаем опцию удаления мимо корзины
                        recycle('on');
                    end
                    % Если работаем в параллельном режиме
                    if strcmp(parallel_mode,'On')
                        % Читаем информацию, о всех LOG файлах, которые лежат в выбранной папке
                        % и перобразуем их слогласно выбранному типу
                        % Создаем обычный массив имен файлов
                        Log_files_info = convertCharsToStrings(app.LOGs_files);
                        [~,~,indx_type_str] = fileparts(app.LOGs_files{1});
                        switch indx_type_str
            
                            case ".MF4" % Значит MF4
                                % Проходим по всем MDF файлам и смотрим законченный ли он. Если нет, то заканчиваем.

                                % Create DataQueue and listener
                                Listener_par_finalize = parallel.pool.DataQueue;
                                % Вызываем функцию объединения полученных таблиц
                                afterEach(Listener_par_finalize,@Par_mdfFinalize);
                                % Проходим по всем MDF файлам и смотрим законченный ли он. Если нет, то заканчиваем.
                                parfor i=1:numel(Log_files_info)
                                    % Заканчиваем незаконченный лог и создаем его копию с припиской "_fin" на конце
                                    % Если у файла нет приписки "fin_" в начале, то значит мы его не заканчивали, тогда закончим его
                                    send(Listener_par_finalize,{LOG_path,Log_files_info(i)});
                                end

                                % Ставим барьер = ждем, когда все ядра завершат обработку
                                spmd
                                    spmdBarrier;
                                end

                                % Читаем информацию, о всех LOG файлах, которые лежат в Log_files_info папке, 
                                % теперь к ним могли добавиться законченные или обработанные, значит их общее количество увеличилось
                                Log_files_info = dir(append(LOG_path,'\fin_*.MF4'));
                                number_of_logs_files = numel(app.LOGs_files);
            

                            case ".asc" % Значит ASC
                                % Проходим по всем ASC файлам 
                                for i=1:numel(Log_files_info)
                                    % Проходя по каждому ASC файлу убираем его шапку, чтобы его можно было прочитать
                                    fid = fopen(strcat(LOG_path, '\', Log_files_info(i)), 'r') ;        % Open source file.
                                    buffer = fread(fid, Inf) ;                                          % Read rest of the file.
                                    fclose(fid);
                                    % Теперь создадим копию без шапки, с которой будем работать
                                    fid = fopen(strcat(LOG_path, '\', "fin_", Log_files_info(i)), 'w')  ;  % Open destination file.
                                    fwrite(fid, buffer) ;                                                  % Save to file.
                                    fclose(fid) ;
                                end
                                % Очищаем буфер считанного файла
                                clear buffer
                                % Читаем информацию, о всех LOG файлах, которые лежат в Log_files_info папке, 
                                % теперь к ним могли добавиться законченные или обработанные, значит их общее количество увеличилось
                                Log_files_info = dir(append(LOG_path,'\fin_*.asc'));
                                number_of_logs_files = numel(app.LOGs_files);
            
                            case ".log" % Значит LOG
                                % Проходим по всем LOG файлам 
                                for i=1:numel(Log_files_info)
                                    % Проходя по каждому LOG файлу убираем его шапку, чтобы его можно было прочитать
                                    fid = fopen(strcat(LOG_path, '\', Log_files_info(i)), 'r') ;        % Open source file.
                                    buffer = fread(fid, Inf) ;                                          % Read rest of the file.
                                    fclose(fid);
                                    
                                    fid = fopen(strcat(LOG_path, '\', "fin_", Log_files_info(i)), 'w')  ;  % Open destination file.
                                    fwrite(fid, buffer) ;                                                  % Save to file.
                                    fclose(fid) ;
                                end
                                % Очищаем буфер считанного файла
                                clear buffer
                                % Читаем информацию, о всех LOG файлах, которые лежат в Log_files_info папке, 
                                % теперь к ним могли добавиться законченные или обработанные, значит их общее количество увеличилось
                                Log_files_info = dir(append(LOG_path,'\fin_*.log'));
                                number_of_logs_files = numel(app.LOGs_files);
            
                            case ".blf" % Значит BLF
                                % Проходим по все blf файлам и копируем их в папку 'C:\Temp', чтобы избежать проблем с кириллицей
                                for i=1:numel(Log_files_info)
                                    copyfile( strcat(LOG_path, '\', Log_files_info(i)), strcat(LOG_path, '\', "fin_", Log_files_info(i)) );
                                    copyfile( strcat(LOG_path, '\', "fin_", Log_files_info(i)), 'C:\Temp' );
                                end
                                % Создаем массив данных о blf-ках во временной директории
                                Log_files_copy_folder = dir(append('C:\Temp','\*.blf'));
                                % Разбиваем blf-ки во временной директории на ASC-шки
                                % Сообщаем о том, что началось разбиение
                                bar.Message = 'Splitting .blf files';
                                % В качестве вывода получаем количество таких файлов
                                count_of_asc = blfSplitToAsc(unique([(signal_and_multiplex{6,:})]), canDB, 'C:\Temp', app.LOGs_files, File_size);
                                % Удаляем финализированные blf во временной директории
                                recycle("off")
                                % Ищем blf во временной директории
                                fin_Log_info_temp = dir(append('C:\Temp','\fin_*.blf'));
                                % Удаляем их из временного фолдера
                                for i=1:numel(fin_Log_info_temp)
                                    delete(append('C:\Temp','\',fin_Log_info_temp(i).name));
                                end
                                recycle("on")
                                % Подготовим значения Log_files_info и app.LOGs_files для обработки через asc
                                Log_files_info = dir(append('C:\Temp','\fin_*.txt'));
                                number_of_logs_files = count_of_asc;
                        end
            
                        % Инициализируем переменную, которая будет считать в скольки файлах отстутвуют ВСЕ нужные нам сигналы
                        fail_file_count = 0;            
                        
                        % В зависимости от выбраннго типа применяем функции чтения и прикладываем DBC
                        switch indx_type_str
                            case ".MF4" % Значит MF4
                                % Создаем массив имен файлов, которые нам нужны
                                Log_files_name = strcat(LOG_path, '\', {Log_files_info.name});
                                % Считываем все финализированные MF4 в один датасет
                                dataAll_datastore = mdfDatastore( Log_files_name(1) );
                                % Находим индексы непустых Channel Group
                                number_of_nonempty = find(dataAll_datastore.ChannelGroups.DataSize>0);
                                % Создаем шаг чтения
                                dataAll_datastore.ReadSize = 'file';
                                % Если в списке присутствуют файлы больших размеров, то читаем не пофайлово а по количественно
                                for i_size = 1:numel(Log_files_info)
                                    if Log_files_info(i_size).bytes > 100000*1024
                                        dataAll_datastore.ReadSize = '50000';
                                        break
                                    end
                                end
                                % Инициализируем массив расписания по каналам и по файлам
                                clear i_table
                                i_table(1,:) = repmat([1:length(number_of_nonempty)],1,number_of_logs_files);
                                i_table(2,:) = sort(repmat([1:number_of_logs_files],1,length(number_of_nonempty)));

                                % Инициализируем бар, но проводим ее только, если зашли в первый фолдер, иначе он будет постоянно обновляться
                                if i_folder == 1
                                    % Инициализируем прогресс бар для паралелльного расчета
                                    w = waitbar(0,'Please wait ...','WindowStyle','modal');
                                    % Create DataQueue and listener
                                    D = parallel.pool.DataQueue;
                                    % Вызываем функцию объединения полученных таблиц
                                    afterEach(D,@Signals_table_join);
                                    % Инициализируем вейт бар
                                    Signals_table_join({-1,w,length(number_of_nonempty)*number_of_remain_files,timetable.empty});
                                end                                    

                                parfor i_channel_file=1:length(number_of_nonempty)*number_of_logs_files
                                    % Стартуем счет врмени
                                    time_par_start = tic;
                                    % Считываем какой канал для чтения и какой файл
                                    i_channel = i_table(1,i_channel_file);
                                    i_file = i_table(2,i_channel_file);
                                    % Считываем все финализированные MF4 в один датасет
                                    dataAll_datastore = mdfDatastore( Log_files_name(i_file) );
                                    % Выбираем канал для чтения
                                    dataAll_datastore.SelectedChannelGroupNumber = number_of_nonempty(i_channel);
                                    % Считываем дату пошагово
                                    timetable_data= read(dataAll_datastore);
                                    % Если таблица непустая, то обрабатываем ее, иначе заканчиваем обработку и помечаем, что тут пусто
                                    if ~isempty(timetable_data)
                                        % Проставляем 8 в графе длины
                                        if (length(number_of_nonempty) > 1)
                                            timetable_data.CAN_DataFrame_DataLength = zeros(height(timetable_data),1) + 8;
                                        end
                                        % Удаляем из записи все те сообщения, ID которых нет в списке нужных нам
                                        logical_count = ismember( uint32(timetable_data.CAN_DataFrame_ID), unique([(signal_and_multiplex{6,:})]) );
                                        % Берем индексы этих элементов
                                        indexes = find(logical_count);
                                        % Убераем ненужные сообщения
                                        timetable_data = timetable_data(indexes,:);
                                        % % Удаляем из таблицы все остальные индексы, т.е. удаляем из таблицы те сообщения, которые нам не нужны
                                        % dataAll = [dataAll;timetable_data]; 
                                        % % Сортируем по времени
                                        % dataAll = sortrows(dataAll,"Time");
                                    end
                                    % Достаем нужные нам сигналы и записываем их в итоговую таблицу Extract_signals
                                    [Extract_signals,empty_status] = Extract_signals_func(timetable_data,signal_and_multiplex,canDB,DBC_path,indx_type_str);
                                    % Заканчиваем счет времени
                                    time_par_stop = toc(time_par_start);
                                    % Проверяем, пустой ли файл
                                    if empty_status
                                        % Если ничего нет -- значит этот файл пустой -- фикисруем это
                                        fail_file_count = fail_file_count + 1;
                                        % Отправляем данные с воркера на клиент
                                        send(D,{time_par_stop,timetable.empty});
                                    else
                                        % Отправляем данные с воркера на клиент
                                        send(D,{time_par_stop,Extract_signals});
                                    end
                                end

                                % Ставим барьер = ждем, когда все ядра завершат обработку
                                spmd
                                    spmdBarrier;
                                end

                                global Extract_signals_all_files
                                % Удвляем бар, только если это последний фолдер
                                if i_folder == length(Unique_folders)
                                    delete(w);
                                end                               
                                       
                                % Сохраняем файлы как таймтейбл в базовый воркспейс и отдуда на диск
                                Unite_and_Save_TimeTable(Extract_signals_all_files,number_of_nonempty,fail_file_count,number_of_logs_files,dt,app,signal_and_multiplex,Extract_signals_save_path,File_save_name,Absent_style)

                                % Сохраняем файлы в базовый воркспейс и отдуда на диск
                                % Unite_and_Save_Signals(Extract_signals_all_files,number_of_nonempty,fail_file_count,number_of_logs_files,dt,app,signal_and_multiplex,Extract_signals_save_path,File_save_name,Absent_style)
                                % Удаляем глобальную переменную
                                clear('global', 'Extract_signals_all_files')

                                % Удаляем временные файлы: обработанные или финализированные логи
                                % Включаем опцию удаления мимо корзины
                                recycle('off');
                                switch indx_type_str
                                        case ".MF4" % Значит MF4
                                            % Чистим датастор, чтобы удалить файлы
                                            clear dataAll_datastore
                                            % Ищем все финализированные логи для удаления
                                            fin_Log_info = dir(append(LOG_path,'\fin_*.MF4'));
                                        case ".asc" % Значит ASC
                                            % Ищем все обработанные (без шапки) логи для удаления
                                            fin_Log_info = dir(append(LOG_path,'\fin_*.asc'));
                                        case ".log" % Значит LOG
                                            % Ищем все обработанные (без шапки) логи для удаления
                                            fin_Log_info = dir(append(LOG_path,'\fin_*.log'));
                                        case ".blf" % Значит BLF
                                            % Ищем все blf-ки во временном фолдере (ОНИ УЖЕ УДАЛЕНЫ, ПОСЛЕ СПЛИТА)
                                            % fin_Log_info_temp = dir(append('C:\Temp','\fin_*.blf'));
                                            % % Удаляем файлы из временного фолдера
                                            % for i=1:numel(fin_Log_info_temp)
                                            %     delete(append('C:\Temp','\',fin_Log_info_temp(i).name));
                                            % end
                    
                                            % Ищем все blf-ки в текущем фолдере
                                            fin_Log_info = dir(append(LOG_path,'\fin_*.blf'));
                    
                                            % Ищем все asc-шки во временном фолдере
                                            fin_Log_info_temp_txt  = dir(append('C:\Temp','\fin_*.txt'));
                                            % Удаляем blf-ки из временного фолдера
                                            for i=1:numel(fin_Log_info_temp_txt)
                                                delete(append('C:\Temp','\',fin_Log_info_temp_txt(i).name));
                                            end
                                end            
                                % Удаляем то,что нашли
                                for i=1:numel(fin_Log_info)
                                    delete(append(LOG_path,'\',fin_Log_info(i).name));
                                end
                                % Выключаем опцию удаления мимо корзины
                                recycle('on');
                        end
                    end
                end
                % Уведомляем о завершении
                bar.Message = 'Finishing';
                pause(1)
            end
            % Завершаем прогресс бар
            close(bar);
            % Засекаем время для тестирования
            time_test_stop = toc(time_test_start)
        end

        % Button pushed function: Clear_All_Button
        function Clear_All_ButtonPushed(app, event)
            app.DBCpathEditField.Value = '';
            app.MessageListBox.Value = {};
            app.MessageListBox.Items = {};
            app.SignalListBox.Value = {};
            app.SignalListBox.Items = {};
            app.Tree_Messages_Signals.Children.delete;
            app.LOGpathEditField.Value = '';
            app.SavepathEditField.Value = '';

            app.EmptyfilestatusLamp.Color = 'w';

            app.MessageListBox.Enable = false;
            app.SignalListBox.Enable = false;
            app.Tree_Messages_Signals.Enable = false;
            app.Convert_LOG_Button.Enable = false;
            app.Clear_All_SignalsButton.Enable = false;

            app.Search_Message_Edit.Enable = false;
        end

        % Button pushed function: Clear_All_SignalsButton
        function Clear_All_SignalsButtonPushed(app, event)
            app.Tree_Messages_Signals.Children.delete;
        end

        % Button pushed function: Browse_DBC_pathButton
        function Browse_DBC_pathButtonPushed(app, event)
            % Создаем фиктивное окно, чтобы оно отправилось на задний план, а не основной интерфейс
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            directory = uigetdir;
            if directory~=0
                app.DBCpathEditField.Value = directory;
            end
            delete(f);
        end

        % Button pushed function: Browse_LOG_filesButton
        function Browse_LOG_filesButtonPushed(app, event)
            % Фолдер/файлы (Off = файлы)
            folder_mode = app.Folder_mode_Switch.Value;
            if strcmp(folder_mode,'Off')
                % Указываем сами логи
                % Создаем фиктивное окно, чтобы оно отправилось на задний план, а не основной интерфейс
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                [files, path, indx_type] = uigetfile({'*.MF4';'*.asc';'*.log';'*.blf'},...
                   'MultiSelect', 'on');
                
                delete(f);
                % Если указывается один файл, то он не записывается как ячека
                % Записываем как ячейка
                if ~iscell(files)
                    % Создаем временную переменную для имени
                    temp_name = files;
                    clear files
                    % Присваеваем в имена файлов запомненное имя, но уже как ячейк
                    files = {temp_name};
                    clear temp_name
                end
                % Если была выбрана функция всех файлов, то начинаем проверку
                if (indx_type == 5) && iscell(files)
                    % Проходим по всем файлам и смотрим одного ли они типа
                    for i=1:length(files)
                        % Запоминаем последние 3 буквы типа
                        last_word = files{i}([end-2:end]);
                        for j=i+1:length(files)
                            if files{i}(end)~=files{j}(end)
                                errordlg('Wrong file types','File Error');
                                return;
                            end
                        end
                    end
                    switch last_word
                        case 'MF4'
                            indx_type = 1;
                        case 'asc'
                            indx_type = 2;
                        case 'log'
                            indx_type = 3;
                        case 'blf'
                            indx_type = 4;
                    end
                end
                if path~=0
                    app.LOGpathEditField.Value = path;
                end
                if indx_type~=0
                    % Записываем сохраняемое имя как имя первого лога:
                    Name_first_file = files{1};
                    % Находим индекс всех точек в имени
                    Point_indexes = strfind(Name_first_file,'.');
                    % Удаляем последнюю точку и разрешение
                    Name_first_file = Name_first_file(1:Point_indexes(end)-1);
                    % Перестрахуемся и заменим все точки в имени на '_'
                    Name_first_file = strrep(Name_first_file,'.','_');
                    app.Save_name_EditField.Value = strcat(Name_first_file);
                end
                app.LOGs_files = files;
            else
                % Указываем фолдеры
                % Данный случай сделан специально для MF4, поэтому
                % Создаем фиктивное окно, чтобы оно отправилось на задний план, а не основной интерфейс
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                % Указываем директорию логов
                directory = uigetdir;
                app.LOGpathEditField.Value = directory;
                delete(f);
            end
        end

        % Button pushed function: BrowseSavefolderButton
        function BrowseSavefolderButtonPushed(app, event)
            % Создаем фиктивное окно, чтобы оно отправилось на задний план, а не основной интерфейс
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            directory = uigetdir;
            if directory~=0
                app.SavepathEditField.Value = directory;
            end
            delete(f);
        end

        % Selection changed function: DBCsetsButtonGroup
        function DBCsetsButtonGroupSelectionChanged(app, event)
            selectedButton = app.DBCsetsButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Bus 121'
                    app.DBCpathEditField.Enable = false;
                    app.Browse_DBC_pathButton.Enable = false;
                case 'Bus 123'
                    app.DBCpathEditField.Enable = false;
                    app.Browse_DBC_pathButton.Enable = false;
                case 'Bus 127'
                    app.DBCpathEditField.Enable = false;
                    app.Browse_DBC_pathButton.Enable = false;
                case 'Bus 129'
                    app.DBCpathEditField.Enable = false;
                    app.Browse_DBC_pathButton.Enable = false;
                case 'Bus 133'
                    app.DBCpathEditField.Enable = false;
                    app.Browse_DBC_pathButton.Enable = false;
                case 'Custom'
                    app.DBCpathEditField.Enable = true;
                    app.Browse_DBC_pathButton.Enable = true;
            end
            app.Tree_Messages_Signals.Children.delete;

            app.MessageListBox.Enable = false;
            app.MessageListBox.Value = {};
            app.MessageListBox.Items = {};

            app.Tree_Messages_Signals.Enable = false;
            app.Tree_Messages_Signals.Children.delete;

            app.Convert_LOG_Button.Enable = false;

            app.SignalListBox.Enable = false;
            app.SignalListBox.Value = {};
            app.SignalListBox.Items = {};

            app.Clear_All_SignalsButton.Enable = false;

            app.Search_Message_Edit.Enable = false;
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            fig = uifigure('Position',[10 10 1000 1100]);
            label_text = sprintf(['%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s ' ...
                '\n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s \n%s'], ...
                '1. Select a method for connecting dbs files:', ...
                '1.1 Select a pre-prepared set, the folder with which is in the same folder as the program', ...
                '1.2 Select a custom dbs set, then specify the path to it', ...
                '2. Confirm the dbs set with the confirm button', ...
                '3. Select a log files (one or several)', ...
                '4. Select the folder in which the matfile will be saved (optional)', ...
                'If nothing is selected, the result will be saved to where the log files were located', ...
                '5. Agree or disagree with the choice of name (optional)', ...
                'It is automatically selected according to the first name in the list of selected files',...
                'Change it if necessary',...
                '6. Select RAM size (optional). The larger the value selected, the greater the memory consumption will be, but the processing will be faster',...
                '7. Select a time step. If 0 is selected, then the signals will have their own time', ...
                '8. Select a message from the message list', ...
                '9. Use the search. The search looks for the presence of a string in the title of a message or in the name of one of its signals', ...
                '10. Select signals from the list of signals with double click', ...
                'To delete a signal or message, double-click on it in the signal tree', ...
                '11. Click Confirm to start converting', ...
                ' ', ...
                'Folder mode allows you to select folders instead of files and only works for the MF4 extension', ...
                'Parallel mode allows you to use multithreading, works only for MF4', ...
                ' ', ...
                'The lamp shows the presence of files that didnt contain any messages from the selected list',...
                'Green -- no empty files', ...
                'Yellow -- empty files are present', ...
                'Red -- all files are empty', ...
                'In the last case, the mat file will not be created', ...
                ' ', ...
                'If a signal is not present in the final file, it is marked in red',...
                ' ', ...
                '1. Выберите способ подключения файлов dbs:', ...
                '1.1 Выбрать заранее подготовленный набор, папка с которым находится в той же папке, что и программа',...
                '1.2 Выберите пользовательский набор баз данных, затем укажите путь к нему', ...
                '2. Подтвердите установку БД кнопкой подтверждения', ...
                '3. Выберите файлы журнала (один или несколько)', ...
                '4. Выберите папку, в которой будет сохранен матфайл (необязательно)', ...
                'Если ничего не выбрано, результат будет сохранен там, где находились файлы журнала', ...
                '5. Согласиться или не согласиться с выбором имени (по желанию)', ... 
                'Он выбирается автоматически по первому имени в списке выбранных файлов',...
                'Поменяй, если надо', ...
                '6. Выберите размер оперативной памяти (необязательно). Чем больше выбранное значение, тем больше будет потребление памяти, но обработка будет быстрее',...
                '7. Выберите временной шаг. Если выбрано 0, то сигналы будут иметь свое время', ...
                '8. Выберите сообщение из списка сообщений', ...
                '9. Воспользуйтесь поиском. Поиск ищет наличие строки в заголовке сообщения или в названии одного из его сигналов',...
                '10. Выбор сигналов из списка сигналов двойным щелчком мыши', ...
                'Чтобы удалить сигнал или сообщение, дважды щелкните по нему в дереве сигналов', ...
                '11. Нажмите «Подтвердить», чтобы начать конвертацию', ...
                ' ', ...
                'Режим фолдеров позволяет выбирать папки вместо файлов и работает только для расширения MF4.', ...
                'Параллельный режим позваляет использовать многопоточность, работает только для MF4', ...
                ' ', ...
                'Лампа показывает наличие файлов, не содержащих сообщений из выбранного списка',...
                'Зеленый — пустых файлов нет', ...
                'Желтый — присутствуют пустые файлы', ...
                'Красный — все файлы пусты', ...
                'В последнем случае мат-файл не будет создан',...
                ' ', ...
                'Если сигнал отсутствует в конечном файле, он отмечается красным');
            lbl = uilabel(fig,'Text',label_text,"Position",[15 10 1000 1100],"WordWrap","on");
            lbl.FontSize = 15;
            % uilabel(fig, ...
            %     'Text', 'my programmatically created label', ...
            %     'Position', [100 100 250 30]);
        end

        % Clicked callback: MessageListBox
        function MessageListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            if ~isempty(item)
                % Полный путь до директории приложения
                [filepath,~,~] = fileparts(mfilename('fullpath'));
                % В зависимости от выбранного режима выбираем либо пресеты, либо прописанный путь
                selectedButton = app.DBCsetsButtonGroup.SelectedObject;
                switch selectedButton.Text
                    case 'Bus 121'
                        DBC_path = horzcat(filepath,'\Bus_6282_0000121');
                    case 'Bus 123'
                        DBC_path = horzcat(filepath,'\Bus_6282_0000123');
                    case 'Bus 127'
                        DBC_path = horzcat(filepath,'\Bus_6282_0000127');
                    case 'Bus 129'
                        DBC_path = horzcat(filepath,'\Bus_6282_0000129');
                    case 'Bus 133'
                        DBC_path = horzcat(filepath,'\Bus_6282_0000133');
                    case 'Custom'
                        DBC_path = app.DBCpathEditField.Value;
                end
                % Читаем информацию, о всех DBC файлах, которые лежат в DBC_path папке
                DBC_info = dir(append(DBC_path,'\*.dbc'));
                % Проверяем подгрузились ли DBC файлы
                if ~isempty(DBC_info)
                    % Загружаем DBC файлы в один массив
                    for i=1:numel(DBC_info)
                        canDB(i) = canDatabase(append(DBC_path,'\',DBC_info(i).name));
                    end
                    % Поиск этого сообщения по всем dbc
                    message_dbc = [];
                    for i=1:length(canDB)
                        message_index = find(convertCharsToStrings(canDB(1, i).Messages) == app.MessageListBox.Items(item));
                        if ~isempty(message_index)
                            message_dbc = i;
                            break
                        end
                    end
                    % Если мы не нашли такой сигнал, значит он дублированный и его строчка выглядит иначе в формате: Name \ id:##### \ dbc:dbc_name
                    if isempty(message_dbc)
                        % Считываем всю строку
                        String_name = char(app.MessageListBox.Items(item));
                        % Берем имя из строки:
                        Name = String_name(1:strfind(String_name,' \ id:')-1);
                        % Берем ID строки
                        ID = String_name(strfind(String_name,' \ id:')+6:strfind(String_name,' \ dbc:')-1);
                        % Берем DBC строки
                        DBC = String_name(strfind(String_name,' \ dbc:')+7:end);
                        % Поиск этого dbc
                        dbc_index = find(convertCharsToStrings({canDB(1, :).Name}) == DBC);
                        % Найдем индекс этого сообщения в этом дбс
                        message_index = find(convertCharsToStrings(canDB(1, dbc_index).Messages) == Name);
                        message_dbc = dbc_index;
                    end
                    % Формируем список сигналов этого сообщения
                    DBC_signals_list = canDB(1, message_dbc).MessageInfo(message_index).Signals;
                    app.SignalListBox.Items = sort(DBC_signals_list);
                    % Поднимаем бегунок наверх
                    scroll(app.SignalListBox,"top");
                end
            end
        end

        % Double-clicked callback: SignalListBox
        function SignalListBoxDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            % Если пользователь промахнулся, но ничего не делаем
            if ~isempty(item)
                % Если это первое получение, то иницивлизируем список сообщений и сигналов
                if isempty(app.Tree_Messages_Signals.Children)
                    % Если это сообщение дублированное, то отсесчем его
                    parent = uitreenode(app.Tree_Messages_Signals,'Text',app.MessageListBox.Value);
                    child = uitreenode(parent,'Text',app.SignalListBox.Items{item});
                else
                    % Если это не первное принятие, то смотрим, было ли уже такое сообщение
                    % Список имен сообщений:
                    message_list = {app.Tree_Messages_Signals.Children.Text};
                    if ~isempty(find(convertCharsToStrings(message_list) == app.MessageListBox.Value))
                        % Если такое сообщение было, смотрим был ли такой сигнал
                        % Получаем список всех узлов
                        allnodes = findall(app.Tree_Messages_Signals,"Type","uitreenode");
                        % Получаем список имен всех узлов
                        nodes_name_list = {allnodes.Text};
                        % Ищем родителя в списке имен всех узлов
                        parent_index = find(convertCharsToStrings(nodes_name_list) == app.MessageListBox.Value);
                        % Получаем родительский узел
                        parent = allnodes(parent_index);
                        % Смотрим на его детей
                        if ~isempty(find(convertCharsToStrings({parent.Children.Text}) == app.SignalListBox.Items(item)))
                            % Если такой сигнал был, то игнорируем и выходим
                            
                        else
                            % Если такого сигнала не было, то добавляем его в список
                            % Ищем родителя этого сигнала
                            % Получаем список всех узлов
                            allnodes = findall(app.Tree_Messages_Signals,"Type","uitreenode");
                            % Получаем список имен всех узлов
                            nodes_name_list = {allnodes.Text};
                            % Ищем родителя в списке имен всех узлов
                            parent_index = find(convertCharsToStrings(nodes_name_list) == app.MessageListBox.Value);
                            % Берем по этому индексу номер родителя из списка всех родителей
                            child = uitreenode(allnodes(parent_index),'Text',app.SignalListBox.Items{item});
                        end
                    else
                        % Если мы получаем это сообщение впервые, то добавляем его и его сигнал
                        parent = uitreenode(app.Tree_Messages_Signals,'Text',app.MessageListBox.Value);
                        child = uitreenode(parent,'Text',app.SignalListBox.Items{item});
                    end
                end
                % Если добавили хотя бы один сигнал, то можно конвертить и чистить
                app.Convert_LOG_Button.Enable = true;
                app.Clear_All_SignalsButton.Enable = true;
                expand(app.Tree_Messages_Signals,'all')
            end
        end

        % Double-clicked callback: Tree_Messages_Signals
        function Tree_Messages_SignalsDoubleClicked(app, event)
            % Если удаляем узел, то уменьшаем число узлов на 1 и удаляем его из списка узлов
            node = event.InteractionInformation.Node;
            try
                % Определим это сообщение или сигнал
                if isempty(node.Children)
                    % Если это сигнал, значит у него нет детей
                    % Посмотрим на его родителя
                    parent_name = node.Parent;
                    delete(node);
                    % Если это был последний ребенок у этого родителя, то после удаления ребенка его тоже нужно удалить
                    if isempty(parent_name.Children)
                        delete(parent_name);
                    end
                else
                    % Если это сообщение, значит у него есть дети
                    % Удаляем узел сообщения (его дети удалятся автоматичекски)
                    delete(node);
                end
                % Если не осталось ни сигналов, ни сообщений, то отключаем кнопку конвертирования
                if isempty(app.Tree_Messages_Signals.Children)
                    app.Convert_LOG_Button.Enable = false;
                end
            catch
            end
        end

        % Value changing function: Search_Message_Edit
        function Search_Message_EditValueChanging(app, event)
            changingValue = event.Value;

            persistent myEditboxFlag

            if isempty(myEditboxFlag) || (convertCharsToStrings(myEditboxFlag)~=convertCharsToStrings(changingValue))
                myEditboxFlag = changingValue;
                app.SignalListBox.Value = {};
                app.SignalListBox.Items = {};
                app.MessageListBox.Items = {};
    
                if isempty(changingValue)
                    app.MessageListBox.Items = app.list_box_all_messages;
                    return
                end
        
                % Поиск по подстроке среди сигналов и сообщений
                % Полный путь до директории приложения
                [filepath,~,~] = fileparts(mfilename('fullpath'));
                pat = [convertCharsToStrings(lower(changingValue))];
                % Собираем данные обо всех dbc
                selectedButton = app.DBCsetsButtonGroup.SelectedObject;
                switch selectedButton.Text
                    case 'Bus 121'
                        DBC_path = horzcat(filepath,'\Bus_6282_0000121');
                    case 'Bus 123'
                        DBC_path = horzcat(filepath,'\Bus_6282_0000123');
                    case 'Bus 127'
                        DBC_path = horzcat(filepath,'\Bus_6282_0000127');
                    case 'Bus 129'
                        DBC_path = horzcat(filepath,'\Bus_6282_0000129');
                    case 'Bus 133'
                        DBC_path = horzcat(filepath,'\Bus_6282_0000133');
                    case 'Custom'
                        DBC_path = app.DBCpathEditField.Value;
                end
                % Читаем информацию, о всех DBC файлах, которые лежат в DBC_path папке
    
                DBC_info = dir(append(DBC_path,'\*.dbc'));
                % Загружаем DBC файлы в один массив
                for i=1:numel(DBC_info)
                    canDB(i) = canDatabase(append(DBC_path,'\',DBC_info(i).name));
                end
    
                % Проходим по всем сообщениям
                % Создаем логический массив 1 -- сообщение с этим индексом есть, 0 -- нет
                logical_index = zeros(length(app.list_box_all_messages),1);
                for i=1:length(app.list_box_all_messages)
                    find_message = 0;
                    % Поиск этого сообщения по всем dbc
                    for j=1:length(canDB)
                        message_index = find(convertCharsToStrings(canDB(1, j).Messages) == app.list_box_all_messages{i});
                        % Если нашли это сообщение
                        if ~isempty(message_index)
                            % Создаем массив строк, первая строка -- имя сообщения, отсальные его сигналы
                            message_and_signals_str = [canDB(1,j).Messages{message_index};convertCharsToStrings(canDB(1,j).MessageInfo(message_index).Signals)];
                            % Проверяем, есть ли в этих строках наш шаблон
                            if find(contains(lower(message_and_signals_str),pat),1) > 0
                                logical_index(i) = 1;
                            end
                            find_message = 1;
                            break
                        end
                    end
                    % Если мы уже нашли ответ, переходим к следующему сообщению
                    if find_message == 1
                        continue
                    end
                    % Если это сообщение не нашли, значит оно дублированное
                    % Собираем его имя и его dbc
                    String_name = char(app.list_box_all_messages{i});
                    % Берем имя из строки:
                    Name = String_name(1:strfind(String_name,' \ id:')-1);
                    % Берем DBC строки
                    DBC = String_name(strfind(String_name,' \ dbc:')+7:end);
                    % Поиск этого dbc
                    dbc_index = find(convertCharsToStrings({canDB(1, :).Name}) == DBC);
                    % Найдем индекс этого сообщения в этом дбс
                    message_index = find(convertCharsToStrings(canDB(1, dbc_index).Messages) == Name);
                    % Создаем массив строк, первая строка -- имя сообщения, отсальные его сигналы
                    message_and_signals_str = [canDB(1,dbc_index).Messages{message_index};convertCharsToStrings(canDB(1,dbc_index).MessageInfo(message_index).Signals)];
                    % Проверяем, есть ли в этих строках наш шаблон
                    if find(contains(lower(message_and_signals_str),pat),1) > 0
                        logical_index(i) = 1;
                    end
                end
                list_after_searsh = app.list_box_all_messages(find(logical_index));
                app.MessageListBox.Items = list_after_searsh;
                % Поднимаем бегунок наверх
                if ~isempty(list_after_searsh)
                    scroll(app.MessageListBox,"top");
                end
            end
        end

        % Selection changed function: Memorry_Controller_ButtonGroup
        function Memorry_Controller_ButtonGroupSelectionChanged(app, event)
            % selectedButton = app.Memorry_Controller_ButtonGroup.SelectedObject;
        end

        % Value changed function: Parallel_mode_Switch
        function Parallel_mode_SwitchValueChanged(app, event)
            value = app.Parallel_mode_Switch.Value;
            app.Parallel_mode_Switch.Enable = false;
            if strcmp(value,'On')
                if canUseParallelPool()
                    % Ставим лампу на зеленый = параллельный пул доступен
                    app.Parallel_status_lamp.Color = 'g';
                    % pause(.1);
                else
                    % Ставим лампу на красный = параллельный пул не доступен
                    app.Parallel_status_lamp.Color = 'r';
                    app.Parallel_mode_Switch.Value = 'Off';
                    % pause(.1);
                end
            else
                % Ставим лампу на белый = параллельный пул не проверяется
                app.Parallel_status_lamp.Color = 'w';
                % pause(.1);
            end
            app.Parallel_mode_Switch.Enable = true;
        end

        % Button pushed function: SaveTreeButton
        function SaveTreeButtonPushed(app, event)
            % Проверяем есть ли сообщения в дереве
            if ~isempty(app.Tree_Messages_Signals.Children)
                % Сохранеем дерево в матфайл
                Message_Signal_Tree = app.Tree_Messages_Signals;
                % Маркируем переменную, что это дерево из N-ого набора ДБС
                selectedButton = app.DBCsetsButtonGroup.SelectedObject;
                Message_Signal_Tree.Tag = selectedButton.Text(end-2:end);
    
                % Создаем фиктивное окно, чтобы оно отправилось на задний план, а не основной интерфейс
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                [Tree_Save_Name,Tree_Save_location] = uiputfile('*.mat');
                delete(f);
    
                % Сохраняем переменную по указанному пути
                t = struct;
                t.children_nodes = Message_Signal_Tree;
                warning('off')
                save( strcat(Tree_Save_location,Tree_Save_Name), "t");
                warning('on')
            end
        end

        % Button pushed function: LoadTreeButton
        function LoadTreeButtonPushed(app, event)
            % Ищем в проводнике сохраненное дерево
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file_Tree,path_Tree]=uigetfile('*.mat');
            delete(f);
            if file_Tree ~= 0
                % Загружаем его
                warning('off')
    
                % Почему-то возникает ошибка при полном завершении фцнкции, "заткнем" ошибку с помощью try-catch
                try
                load(fullfile(path_Tree,file_Tree));
                catch
                end

                % Проверяем, что мы закгрузили дерево
                if ~((exist('t','var')) && (isstruct(t)) && (isfield(t,'children_nodes')) && (strcmp(class(t.children_nodes), 'matlab.ui.container.Tree')))
                    errordlg('Invalid matfile type','Load tree error');
                    return;
                end

                % Проверяем, соответствует ли маркировка шаблона дерева тому ДБС, который выбран
                selectedButton = app.DBCsetsButtonGroup.SelectedObject;
                % Если не соответствует, то выдаем ошибку и выходим из функции
                if ~strcmp(selectedButton.Text(end-2:end), t.children_nodes.Tag )
                    errordlg('DBC tag dont match with selected DBC set','Load tree error');
                    return;
                end
                % Удаляем старые узлы
                delete(app.Tree_Messages_Signals.Children)
                
                % Считываем детей дерева (т.е. сообщения)
                Tree_messages = t.children_nodes.Children;
                % Проходим по каждому сообщению
                for i=1:length(Tree_messages)
                    tree1(i) = uitreenode(app.Tree_Messages_Signals);
                    tree1(i).Text = Tree_messages(i).Text;
                    Tree_Signals = Tree_messages(i).Children;
                    % Подгружаем его детей (т.е. его сигналы)
                    for j=1:length(Tree_Signals)
                        tree2(i,j) = uitreenode(tree1(i));
                        tree2(i,j).Text = Tree_Signals(j).Text;
                    end
                end
                warning('on')
                % Раскрываем все узлы
                expand(app.Tree_Messages_Signals,'all')
                % Если добавили дерево, то разрешаем чистить и конвертировать
                app.Convert_LOG_Button.Enable = true;
                app.Clear_All_SignalsButton.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DBCpathEditFieldLabel = uilabel(app.UIFigure);
            app.DBCpathEditFieldLabel.Text = 'DBC path';
            app.DBCpathEditField = uieditfield(app.UIFigure, 'text');
            app.Browse_DBC_pathButton = uibutton(app.UIFigure, 'push');
            app.Browse_DBC_pathButton.ButtonPushedFcn = createCallbackFcn(app, @Browse_DBC_pathButtonPushed, true);
            app.Browse_DBC_pathButton.Text = 'Browse DBC folder';
            app.Memorry_Controller_ButtonGroup = uibuttongroup(app.UIFigure);
            app.Memorry_Controller_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Memorry_Controller_ButtonGroupSelectionChanged, true);
            app.Memorry_Controller_ButtonGroup.Title = 'RAM';
            app.Gb_16_button = uiradiobutton(app.Memorry_Controller_ButtonGroup);
            app.Gb_16_button.Text = '64 Gb';
            app.Gb_16_button.Value = true;
            app.Gb_32_button = uiradiobutton(app.Memorry_Controller_ButtonGroup);
            app.Gb_32_button.Text = '32 Gb';
            app.Gb_64_button = uiradiobutton(app.Memorry_Controller_ButtonGroup);
            app.Gb_64_button.Text = '16 Gb';
            app.DBCsetsButtonGroup = uibuttongroup(app.UIFigure);
            app.DBCsetsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DBCsetsButtonGroupSelectionChanged, true);
            app.DBCsetsButtonGroup.Title = 'DBC sets';
            app.Bus121Button = uiradiobutton(app.DBCsetsButtonGroup);
            app.Bus121Button.Text = 'Bus 121';
            app.Bus123Button = uiradiobutton(app.DBCsetsButtonGroup);
            app.Bus123Button.Text = 'Bus 123';
            app.Bus127Button = uiradiobutton(app.DBCsetsButtonGroup);
            app.Bus127Button.Text = 'Bus 127';
            app.Bus129Button = uiradiobutton(app.DBCsetsButtonGroup);
            app.Bus129Button.Text = 'Bus 129';
            app.Bus133Button = uiradiobutton(app.DBCsetsButtonGroup);
            app.Bus133Button.Text = 'Bus 133';
            app.CustomButton = uiradiobutton(app.DBCsetsButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.CustomButton.Value = true;
            app.EmptyfilestatusLampLabel = uilabel(app.UIFigure);
            app.EmptyfilestatusLampLabel.Text = 'Empty file status';
            app.EmptyfilestatusLamp = uilamp(app.UIFigure);
            app.LOGpathEditFieldLabel = uilabel(app.UIFigure);
            app.LOGpathEditFieldLabel.Text = 'LOG path';
            app.LOGpathEditField = uieditfield(app.UIFigure, 'text');
            app.Browse_LOG_filesButton = uibutton(app.UIFigure, 'push');
            app.Browse_LOG_filesButton.ButtonPushedFcn = createCallbackFcn(app, @Browse_LOG_filesButtonPushed, true);
            app.Browse_LOG_filesButton.Text = 'Browse LOG files';
            app.ParaccessstatusLampLabel = uilabel(app.UIFigure);
            app.ParaccessstatusLampLabel.Text = 'Par access status';
            app.Parallel_status_lamp = uilamp(app.UIFigure);
            app.Folder_mode_Switch = uiswitch(app.UIFigure, 'toggle');
            app.Parallel_mode_Switch = uiswitch(app.UIFigure, 'toggle');
            app.Parallel_mode_Switch.ValueChangedFcn = createCallbackFcn(app, @Parallel_mode_SwitchValueChanged, true);
            app.SavepathEditFieldLabel = uilabel(app.UIFigure);
            app.SavepathEditFieldLabel.Text = 'Save path';
            app.SavepathEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseSavefolderButton = uibutton(app.UIFigure, 'push');
            app.BrowseSavefolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSavefolderButtonPushed, true);
            app.BrowseSavefolderButton.Text = 'Browse Save folder';
            app.SavenameEditFieldLabel = uilabel(app.UIFigure);
            app.SavenameEditFieldLabel.Text = 'Save name';
            app.Save_name_EditField = uieditfield(app.UIFigure, 'text');
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = '.mat';
            app.Confirm_DBC_path_Button = uibutton(app.UIFigure, 'push');
            app.Confirm_DBC_path_Button.ButtonPushedFcn = createCallbackFcn(app, @Confirm_DBC_path_ButtonPushed, true);
            app.Confirm_DBC_path_Button.Text = 'Confirm';
            app.FoldermodeSwitchLabel = uilabel(app.UIFigure);
            app.FoldermodeSwitchLabel.Text = {'Folder'; 'mode'};
            app.ParallelmodeSwitchLabel = uilabel(app.UIFigure);
            app.ParallelmodeSwitchLabel.Text = {'Parallel'; 'mode'};
            app.TimestepEditFieldLabel = uilabel(app.UIFigure);
            app.TimestepEditFieldLabel.Text = 'Time step';
            app.Time_step_Value_EditField = uieditfield(app.UIFigure, 'numeric');
            app.SearchEditFieldLabel = uilabel(app.UIFigure);
            app.SearchEditFieldLabel.Text = 'Search';
            app.Search_Message_Edit = uieditfield(app.UIFigure, 'text');
            app.Search_Message_Edit.ValueChangingFcn = createCallbackFcn(app, @Search_Message_EditValueChanging, true);
            app.Search_Message_Edit.Enable = 'off';
            app.MessagelistLabel = uilabel(app.UIFigure);
            app.MessagelistLabel.Text = {'Message'; '          list'};
            app.MessageListBox = uilistbox(app.UIFigure);
            app.MessageListBox.Items = {};
            app.MessageListBox.Enable = 'off';
            app.MessageListBox.ClickedFcn = createCallbackFcn(app, @MessageListBoxClicked, true);
            app.MessageListBox.Value = {};
            app.SignallistListBoxLabel = uilabel(app.UIFigure);
            app.SignallistListBoxLabel.Text = {'Signal'; 'list'};
            app.SignalListBox = uilistbox(app.UIFigure);
            app.SignalListBox.Items = {};
            app.SignalListBox.Enable = 'off';
            app.SignalListBox.DoubleClickedFcn = createCallbackFcn(app, @SignalListBoxDoubleClicked, true);
            app.SignalListBox.Value = {};
            app.Tree_Messages_Signals = uitree(app.UIFigure);
            app.Tree_Messages_Signals.Enable = 'off';
            app.Tree_Messages_Signals.DoubleClickedFcn = createCallbackFcn(app, @Tree_Messages_SignalsDoubleClicked, true);
            app.Clear_All_SignalsButton = uibutton(app.UIFigure, 'push');
            app.Clear_All_SignalsButton.ButtonPushedFcn = createCallbackFcn(app, @Clear_All_SignalsButtonPushed, true);
            app.Clear_All_SignalsButton.Enable = 'off';
            app.Clear_All_SignalsButton.Text = 'Clear signals';
            app.Clear_All_Button = uibutton(app.UIFigure, 'push');
            app.Clear_All_Button.ButtonPushedFcn = createCallbackFcn(app, @Clear_All_ButtonPushed, true);
            app.Clear_All_Button.Text = 'Clear';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.Convert_LOG_Button = uibutton(app.UIFigure, 'push');
            app.Convert_LOG_Button.ButtonPushedFcn = createCallbackFcn(app, @Convert_LOG_ButtonPushed, true);
            app.Convert_LOG_Button.Enable = 'off';
            app.Convert_LOG_Button.Text = 'Convert';
            app.LoadTreeButton = uibutton(app.UIFigure, 'push');
            app.LoadTreeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTreeButtonPushed, true);
            app.LoadTreeButton.Enable = 'off';
            app.LoadTreeButton.Text = 'Load Tree';
            app.SaveTreeButton = uibutton(app.UIFigure, 'push');
            app.SaveTreeButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTreeButtonPushed, true);
            app.SaveTreeButton.Enable = 'off';
            app.SaveTreeButton.Text = 'Save Tree';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end